classdef ARES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout19                    matlab.ui.container.GridLayout
        MainTabs                        matlab.ui.container.TabGroup
        InfoTab                         matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        Hyperlink2_3                    matlab.ui.control.Hyperlink
        Hyperlink2_2                    matlab.ui.control.Hyperlink
        Hyperlink2                      matlab.ui.control.Hyperlink
        AuthorJoseAbrahanBolaosVargasMentorAlexDSantiagoVargasLabel  matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        V02BETARelaseLabel              matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        PowerAmplifierTab               matlab.ui.container.Tab
        GridLayout28                    matlab.ui.container.GridLayout
        MeasurementSettingsPanel        matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        PlotPAData                      matlab.ui.control.Button
        SavePAData                      matlab.ui.control.Button
        LoadPAData                      matlab.ui.control.Button
        StartPAMeasurement              matlab.ui.control.Button
        PASettingsPanel                 matlab.ui.container.TabGroup
        InstrumentsTab                  matlab.ui.container.Tab
        GridLayout27                    matlab.ui.container.GridLayout
        MeasurementDelayValueField      matlab.ui.control.NumericEditField
        MeasurementDelaysEditFieldLabel  matlab.ui.control.Label
        MeasurementTypeDropDown         matlab.ui.control.DropDown
        MeasurementTypeDropDownLabel    matlab.ui.control.Label
        SpectrumAnalyzerLabel           matlab.ui.control.Label
        SpectrumAnalyzerDropDown        matlab.ui.control.DropDown
        SignalGeneratorLabel            matlab.ui.control.Label
        SignalGeneratorDropDown         matlab.ui.control.DropDown
        PowerSupplyBLabel               matlab.ui.control.Label
        PowerSupplyBDropDown            matlab.ui.control.DropDown
        PowerSupplyADropDown            matlab.ui.control.DropDown
        PowerSupplyALabel               matlab.ui.control.Label
        SpectrumAnalyzerStatusLabel     matlab.ui.control.Label
        SignalGeneratorStatusLabel      matlab.ui.control.Label
        PowerSupplyBStatusLabel         matlab.ui.control.Label
        PowerSupplyAStatusLabel         matlab.ui.control.Label
        PAConnectButton                 matlab.ui.control.Button
        PAScanButton                    matlab.ui.control.Button
        PowerSupplyTab                  matlab.ui.container.Tab
        GridLayout29                    matlab.ui.container.GridLayout
        SupplyChannelDropDownLabel      matlab.ui.control.Label
        ModeDropDownLabel               matlab.ui.control.Label
        SupplyVoltageStepValueField     matlab.ui.control.NumericEditField
        SupplyVoltageStepNameField      matlab.ui.control.Label
        SupplyVoltageStopValueField     matlab.ui.control.NumericEditField
        SupplyVoltageStopEditField      matlab.ui.control.Label
        SupplyVoltageStartValueField    matlab.ui.control.NumericEditField
        SupplyVoltageStartNameField     matlab.ui.control.Label
        LimitingCurrentALabel           matlab.ui.control.Label
        SupplyCurrentValueField         matlab.ui.control.NumericEditField
        SingleSweepSwitch               matlab.ui.control.Switch
        DrainGateSwitch                 matlab.ui.control.Switch
        PowerSupplyChannelDropDown      matlab.ui.control.DropDown
        PowerSupplyModeDropDown         matlab.ui.control.DropDown
        SignalGeneratorTab              matlab.ui.container.Tab
        GridLayout24                    matlab.ui.container.GridLayout
        PowerStep                       matlab.ui.control.NumericEditField
        EndPower                        matlab.ui.control.NumericEditField
        StartPower                      matlab.ui.control.NumericEditField
        FrequencyStep                   matlab.ui.control.NumericEditField
        EndFrequency                    matlab.ui.control.NumericEditField
        StartFrequency                  matlab.ui.control.NumericEditField
        PowerStepdBmEditField           matlab.ui.control.Label
        EndPowerdBmEditFieldLabel       matlab.ui.control.Label
        StartPowerdBmEditFieldLabel     matlab.ui.control.Label
        FrequencyStepMHzLabel           matlab.ui.control.Label
        StartFrequencyMHzLabel          matlab.ui.control.Label
        EndFrequencyMHzLabel            matlab.ui.control.Label
        SpectrumAnalyzerTab             matlab.ui.container.Tab
        GridLayout25                    matlab.ui.container.GridLayout
        AttenuationValueField           matlab.ui.control.NumericEditField
        AttenuationdBEditFieldLabel     matlab.ui.control.Label
        ReferenceLevelValueField        matlab.ui.control.NumericEditField
        ReferenceLevelNameField         matlab.ui.control.Label
        SweepPointsValueField           matlab.ui.control.NumericEditField
        SweepPointsNameField            matlab.ui.control.Label
        SpanValueField                  matlab.ui.control.NumericEditField
        SpanNameField                   matlab.ui.control.Label
        CenterFrequencyValueField       matlab.ui.control.NumericEditField
        CenterFrequencyNameField        matlab.ui.control.Label
        CalibrationTab                  matlab.ui.container.Tab
        ResultsTabs                     matlab.ui.container.TabGroup
        SingleTab                       matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        SingleGain                      matlab.ui.control.UIAxes
        SweepTab                        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        CompressionPointsPlot           matlab.ui.control.UIAxes
        PeakDEPAEPlot                   matlab.ui.control.UIAxes
        PeakGainPlot                    matlab.ui.control.UIAxes
        GainvsOutputPowerPlot           matlab.ui.control.UIAxes
        AntennaTab                      matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        TestandPlotSettingsPanel        matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        StopTestButton                  matlab.ui.control.Button
        LoadResultsButton               matlab.ui.control.Button
        PlotResultsButton               matlab.ui.control.Button
        StartTestButton                 matlab.ui.control.Button
        PlotAngleDropDown               matlab.ui.control.DropDown
        PlottingAngleLabel              matlab.ui.control.Label
        PlotFrequencyGHzDropDown        matlab.ui.control.DropDown
        PlotFrequencyGHzDropDownLabel   matlab.ui.control.Label
        InstrumentConnectionsPanel_2    matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        EMCenterDropDown                matlab.ui.control.DropDown
        EMCenterDropDownLabel           matlab.ui.control.Label
        VNADropDown                     matlab.ui.control.DropDown
        VNADropDownLabel                matlab.ui.control.Label
        AntennaConnectButton            matlab.ui.control.Button
        AntennaScanButton               matlab.ui.control.Button
        AntennaSettings                 matlab.ui.container.TabGroup
        VNATab                          matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        SweepPointsLabel                matlab.ui.control.Label
        MeasurementDropDown_2Label      matlab.ui.control.Label
        VNAEndFrequency                 matlab.ui.control.NumericEditField
        VNAStartFrequency               matlab.ui.control.NumericEditField
        VNASweepPoints                  matlab.ui.control.NumericEditField
        VNAMeasurement                  matlab.ui.control.DropDown
        EndFrequencyMHzLabel_6          matlab.ui.control.Label
        StartFrequectSpanMHzLabel_15    matlab.ui.control.Label
        Table                           matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        MovetoAngleButton               matlab.ui.control.Button
        TableSpeedSlider                matlab.ui.control.Slider
        TableSpeedSliderLabel           matlab.ui.control.Label
        TableNewAngle                   matlab.ui.control.NumericEditField
        StopTableButton                 matlab.ui.control.Button
        TableEndAngle                   matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_13    matlab.ui.control.Label
        TableStepAngle                  matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_12    matlab.ui.control.Label
        TableStartAngle                 matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_11    matlab.ui.control.Label
        TowerTab                        matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        MoveTowertoAngleButton          matlab.ui.control.Button
        TowerNewAngle                   matlab.ui.control.NumericEditField
        TowerSpeedSlider                matlab.ui.control.Slider
        TowerSpeedSliderLabel           matlab.ui.control.Label
        StopTowerButton                 matlab.ui.control.Button
        TowerEndAngle                   matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_18    matlab.ui.control.Label
        TowerStepAngle                  matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_17    matlab.ui.control.Label
        TowerStartAngle                 matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_16    matlab.ui.control.Label
        LinearSliderTab                 matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        AntennaPhysicalSize             matlab.ui.control.NumericEditField
        AntennaOffsetLabel              matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        LinearSliderNewPosition         matlab.ui.control.NumericEditField
        MovetoPositionButton            matlab.ui.control.Button
        LinearSliderCurrentPosition     matlab.ui.control.NumericEditField
        SliderPositioncmLabel           matlab.ui.control.Label
        AntennaCurrentSpacing           matlab.ui.control.NumericEditField
        CurrentSpacingLabel             matlab.ui.control.Label
        SpeedPresetDropDown             matlab.ui.control.DropDown
        SpeedPresetDropDownLabel        matlab.ui.control.Label
        StopLinearSliderButton          matlab.ui.control.Button
        AntennaPattern                  matlab.ui.container.TabGroup
        MeasurementAntennaGainTab       matlab.ui.container.Tab
        GridLayout3_2                   matlab.ui.container.GridLayout
        BrowseReferenceFileButton       matlab.ui.control.Button
        ReferenceGainFileField          matlab.ui.control.EditField
        PlotReferenceGainButton         matlab.ui.control.Button
        GainvsFrequencyBoresightGain    matlab.ui.control.UIAxes
        ReturnLossBoresightGain         matlab.ui.control.UIAxes
        DPatternMeasurementTab          matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        GainvsAngle2D                   matlab.ui.control.UIAxes
        GainvsFrequency2D               matlab.ui.control.UIAxes
        ReturnLoss2D                    matlab.ui.control.UIAxes
        Tab                             matlab.ui.container.Tab
        InstrumentConnectionsPanel      matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        SpectrumAnalyzerDropDown_2      matlab.ui.control.DropDown
        SpectrumAnalyzerDropDown_2Label  matlab.ui.control.Label
        SignalGeneratorDropDown_2       matlab.ui.control.DropDown
        SignalGeneratorDropDown_2Label  matlab.ui.control.Label
        PowerSupplyDropDown_2           matlab.ui.control.DropDown
        PowerSupplyDropDown_2Label      matlab.ui.control.Label
        InstrumentConnectionsPanel_3    matlab.ui.container.Panel
        PAConnectButton_2               matlab.ui.control.Button
        PAScanButton_2                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        %% App Settings
        LINEAR_SLIDER_RANGE = 2    % Maximum linear slider movement value (2 meters)
        EXCEL_MAX_ROWS = 1048576;  % Maximum number of rows in Excel
        EXCEL_MAX_COLUMNS = 16384; % Maximum number of columns in Excel
        StopRequested = false;     % Boolean flag: true if user requested a measurement to stop, false otherwise.
        RadiationPlot2D

        %% Instrument Objects
        VNA                        % Vector Network Analyzer
        EMCenter                   % Anechoic Chamber Control  
        LinearSlider               % Linear Slider

        %% Power Amplifier Properties
        PSUMode
        ChannelNames
        DCGatePower
        TypeFreqMeasurement        % PA measurement type: single or sweep frequencies
        inputFrequencySingle       % Frequency scalar for single measurement (Hz)
        inputPdBmSingle            % RF Input Power for single measurement (dBm)
        inputFrequencySweep        % Frequency array for sweep measurement (Hz)
        inputPdBmSweep             % RF Input Power for sweep measurement (dBm)
        outputPdBm                 % RF Output Power (dBm)
        DCDrainPower               % DC Drain Power (W)
        PAGain                     % Power Amplifier Gain (dB)
        DE                         % Drain Efficiency (%)
        PAE                        % Power-Added Efficiency (%)

        %% Antenna Properties
        % Plot Properties
        azimuthAngles              % Measured azimuth angles (deg)
        elevationAngles            % Measured elevation anges (deg)
        sweepFrequencies           % Measured VNA sweep frequencies (Hz)
        antennaRL                  % Measured antenna return loss (dB)
        antennaGain                % Measured antenna gain (dBi)
        selectedAngle              % User selected angle to plot
        selectedFrequency          % User selected frequency to plot 
        angleRange                 % 
        frequencyRange             % 

        % Measurement Properties
        ReferenceGainFile          %
        AzimuthAngles              %
        TestFrequencies            %
        S11                        % 
        S21                        %
        S22                        %
        AntennaGain_dBi            %
        offsetSpacing = 0.8062;    % Spacing between antennas (m)
        linearSpacing              % Spacing added by linear slider (m)
        setupSpacing               % Total spacing between antennas accounting for LS and offset (m)
    end
    
    properties (Access = public)
        %% Instrument Objects
        PowerSupplyA               % Power Supply A 
        PowerSupplyB               % Power Supply B
        SignalGenerator            % Signal Generator
        SpectrumAnalyzer           % Spectrum Analyzer

        %% Power Amplifier Properties
        FilledPSUChannels
        ChannelToDeviceMap
        DrainChannels
        GateChannels
    end
   
    
    methods (Access = private)

        function getInstrumentResources(app, instrumentNames)
            % This function scans for connected VISA resources, retrieves their resource names, and dynamically updates the dropdown menus 
            % for the specified instruments. It clears existing menu items and adds new ones, including a "None" option, allowing the user 
            % to choose from currently available instruments or leave the selection blank.

            app.AntennaScanButton.Enable = 'off';
            try 
                % Retrieve the list of VISA resources with a specified timeout
                timeout = 60; % seconds
                resourceList = visadevlist("Timeout", timeout);
                allResources = resourceList.ResourceName';
            
                % Clear dropdown items for each instrument 
                for i = 1:numel(instrumentNames)
                    dropdownFieldName = strcat(instrumentNames{i}, 'DropDown');
                    if isprop(app, dropdownFieldName) 
                        app.(dropdownFieldName).Items = {};
                    end
                end
     
                % Prepare the dropdown items (with "None" option)
                dropdownItems = [allResources, "None"];

                % Assign scanned addresses to the dropdown menus
                for i = 1:numel(allResources)
                    if i > numel(instrumentNames)
                        break
                    else
                        dropdownFieldName = strcat(instrumentNames{i}, 'DropDown');
                        app.(dropdownFieldName).Items = dropdownItems;
                    end
                end
            catch ME
                app.displayError(ME);
            end
            app.AntennaScanButton.Enable = 'on';
        end

        function newgetInstrumentResources(app, instrumentNames)
            try
                timeout = 60;
                resourceList = visadevlist("Timeout", timeout);
                allResources= resourceList.ResourceName';

                if isempty(allResources)
                    allResources = "None";
                end

                for i = 1:numel(instrumentNames)
                    dropdownFieldName = strcat(instrumentNames{i}, 'Dropdown');
                    if isprop(app, dropdownFieldName)
                        existingItems = app.(dropdownFieldName).Items;
                        newItems = [allResources, "None"];

                        if ~isequal(existingItems, newItems)
                            app.(dropdownFieldName).Items = newItems;
                        end
                    end
                end
            catch ME
                app.displayError(ME);
            end
        end

        function newconnectInstrument(app, instrumentNames, instrumentResources)
            % This function establishes a connection to a set of instruments using their VISA resource names. It iterates through 
            % the provided instrument names and resources, checks if the instrument is enabled for connection, and attempts to connect 
            % using MATLAB’s visadev function. Upon successful connection, the function updates the UI with the connection status and 
            % device information; if the connection fails, it displays an error message.
            for i = 1:numel(instrumentNames)
                instrumentName = instrumentNames{i};
                instrumentResource = instrumentResources{i};
                dropdownFieldName = strcat(instrumentName, 'DropDown');
                statusLabelField = strcat(instrumentName, 'StatusLabel'); 

                if strcmp(app.(dropdownFieldName).Enable, 'on')
                    if ~isempty(instrumentResource) && ~strcmp(instrumentResource, 'None')
                        try
                            app.(instrumentName) = visadev(instrumentResource);
                            app.(instrumentName).ByteOrder = 'little-endian';
                            app.(dropdownFieldName).Enable = 'off';
                            idn = writeread(app.(instrumentName), '*IDN?');
                            idnParts = split(idn, ",");  

                            % Update status label to show success
                            app.(statusLabelField).Text = "Connected";
                            app.(statusLabelField).Text = sprintf("Connected to %s", idnParts{2});
                        catch ME
                            % Update status label to show failure
                            app.(statusLabelField).Text = "Failed";
                            disp('Connection to instrument failed')
                        end
                    else
                        % Update status label if "None" is selected
                        app.(statusLabelField).Text = "Not Connected";
                    end
                end
            end
        end
                                
        
        % function connectInstrument(app, instrumentNames, instrumentResources)
        %     % This function checks if the connection to the instrument is
        %     % valid, and connects the specified address of the instrument.   
        %     for i = 1:numel(instrumentNames)
        %         instrumentName = instrumentNames{i};
        %         instrumentResource = instrumentResources{i};
        %         dropdownFieldName = strcat(instrumentName, 'DropDown');
        % 
        %         % Check if the dropdown is enabled, indicating no existing connection
        %         if strcmp(app.(dropdownFieldName).Enable, 'on')
        %             if ~isempty(instrumentResource)
        %                 try
        %                     app.(instrumentName) = visadev(instrumentResource);
        %                     if isvalid(app.(instrumentName))
        %                         app.(instrumentName).ByteOrder = 'little-endian';
        %                         app.(dropdownFieldName).Enable = 'off';
        %                     else
        %                         error('Invalid VISA object');
        %                     end
        %                 catch ME
        %                     uialert(app.UIFigure, sprintf('Failed to connect to %s: %s.', instrumentResource, ME.message), 'Error', 'Icon', 'warning');
        %                 end
        %             end
        %         else
        %             continue
        %         end
        %     end
        % end


        function populatePSUChannels(app)
            filledChannels = {};
            % To get all channel names.
            channelNames = fieldnames(app.ChannelNames); 

            for channelName = channelNames'
                channel = app.ChannelNames.(channelName{1});
                % Based on the channel mode single/sweep determine if the
                % values entered by the user make it complete.
                if strcmp(channel.Mode, 'Single') && all([channel.Current, channel.Start] ~= 0)
                    filledChannels{end + 1} = channelName{1};
                elseif strcmp(channel.Mode, 'Sweep') && all([channel.Current, channel.Start, channel.Stop, channel.Step] ~= 0)
                    filledChannels{end + 1} = channelName{1};
                end
            end
            % Update the app's list of filled channels which holds the
            % channels that should be passed on to the PSU object.
            app.FilledPSUChannels = filledChannels;

            % Debugging Output.
            disp(['Valid channels: ', strjoin(filledChannels, ', ')]);
        end


        function isValid = validatePSUChannels(app)
            %% DONE
            filledChannels = app.FilledPSUChannels;
            numfilledChannels = length(filledChannels);
            currentMode = app.PSUMode;
            % Standardize mode format so no MATLAB dropdown menu errors
            % occur with the current mode variable type.
            if isnumeric(currentMode)
                modes = {'Single Supply', 'Dual Supply', 'Quad Supply'};
                currentMode = modes{currentMode};
            end
            % Validation check: no power supplies connected at all.
            if isempty(app.PowerSupplyA) && isempty(app.PowerSupplyB)
                uialert(app.UIFigure, 'No power supplies connected. Please connect at least one power supply before proceeding.', 'No Devices Connected');
                isValid = false;
                return;
            end
            % Validation check: quad supply mode selected but number of power supplies
            % avaliable is not 2 (PSUA, and PSUB).
            if strcmp(currentMode, 'Quad Supply') && (isempty(app.PowerSupplyA) || isempty(app.PowerSupplyB))
                uialert(app.UIFigure, 'Quad Supply mode requires both PSUA and PSUB to be connected.', 'Invalid Configuration');
                isValid = false;
                return;
            end
            % Validation check: user tried to run the test with no channels
            % configured, additionally prompt the user how many channels
            % they should configure based on the selected supply mode.
            if isempty(filledChannels)
                switch currentMode
                    case 'Single Supply'
                        message = sprintf('Please configure voltage values for at least one channel before proceeding.');
                        uialert(app.UIFigure, message, 'Insufficient Channels Configured');
                        isValid = false;
                        return;
                    case 'Dual Supply'
                        message = sprintf('Please configure voltage values for at least two channels before proceeding.');
                        uialert(app.UIFigure, message, 'Insufficient Channels Configured');
                        isValid = false;
                        return;
                    case 'Quad Supply'
                        message = sprintf('Please configure voltage values for at least four channels before proceeding.');
                        uialert(app.UIFigure, message, 'Insufficient Channels Configured');
                        isValid = false;
                        return;
                end
            end
            % Determine the allowed number of channels for the selected mode
            switch currentMode
                case 'Single Supply'
                    maxChannels = 1;
                case 'Dual Supply'
                    maxChannels = 2;
                case 'Quad Supply'
                    maxChannels = 4;
                otherwise
                    maxChannels = 0;
            end
            % Validation check: Too many channels are selected for the
            % chosen mode, tell the user to reset all channels or change
            % the supply mode.
            if numfilledChannels > maxChannels
                message = sprintf(['Current mode %s allows %d channel(s).\n\n' ...
                    'Configured channels: %s\n\n' ...
                    'Would you like to:\n' ...
                    '1. Clear all channel values\n' ...
                    '2. Change PSU mode'], ...
                    currentMode, maxChannels, strjoin(filledChannels, ', '));
                choice = uiconfirm(app.UIFigure, message, 'Channel Configuration', ...
                    'Options', {'Clear All Channels', 'Change Mode', 'Cancel'});
                switch choice
                    case 'Clear All Channels'
                        % Reset all channel values.
                        app.resetPSUChannels();
                        isValid = false;
                        return;
                    case 'Change Mode'
                        % Show menu with available modes based on the
                        % number of filled channels.
                        modes = {'Single Supply', 'Dual Supply', 'Quad Supply'};
                        validModes = modes(numfilledChannels <= [1 2 4]);
                        [idx, tf] = listdlg('ListString', validModes, ...
                            'SelectionMode', 'single', ...
                            'PromptString', 'Select new PSU mode:', ...
                            'ListSize', [200, 100]);
                        if tf
                            app.PowerSupplyModeDropDown.Value = validModes{idx};
                            app.PSUChangeMode([]); 
                        end
                        isValid = false;
                        return;
                    otherwise % Cancel option.
                        isValid = false;
                        return;
                end
            end
            % All validation checks have been passed.
            isValid = true;
        end


        function resetPSUChannels(app)
            % This function resets all the PSU channels to the default 
            % state.
            
            % Loop through all the channel and set the default values.
            channels = fieldnames(app.ChannelNames);
            for i = 1:length(channels)
                app.ChannelNames.(channels{i}) = struct('Current', 0, 'Start', 0, 'Stop', 0, 'Step', 0, 'Mode', 'Single');
            end

            % To invalidate the channel once its reset.
            app.populatePSUChannels();

            % Set all remaining GUI values and elements to their default 
            % state.
            app.SupplyCurrentValueField.Value = 0;
            app.SupplyVoltageStartValueField.Value = 0;
            app.SupplyVoltageStopValueField.Value = 0;
            app.SupplyVoltageStepValueField.Value = 0;
            app.SingleSweepSwitch.Value = 'Single';
            app.SupplyVoltageStopValueField.Visible = 'off';
            app.SupplyVoltageStepValueField.Visible = 'off';
        end


        function setPSUChannels(app, deviceChannel, voltage, current)
            %% DONE
            % Get the correct PSU object and channel number.
            [physicalChannel, psuName] = strtok(app.ChannelToDeviceMap(deviceChannel), ',');
            psuName = psuName(2:end);  % Removes the comma.
            % Select the correct PSU object
            if strcmp(psuName, 'PSUA')
                PSU = app.PowerSupplyA;
            else
                PSU = app.PowerSupplyB;
            end
            % Handle string or numeric values for parameters.
            if isnumeric(voltage)
                voltageStr = num2str(voltage);
            else
                voltageStr = voltage;
            end
            if isnumeric(current)
                currentStr = num2str(current);
            else
                currentStr = current;
            end
            % Apply voltage and current values to the PSU channel.
            writeline(PSU, sprintf(':APPLy %s,%s,%s', physicalChannel, voltageStr, currentStr));
        end

        function enablePSUChannels(app, channels, state)
            %% DONE
            % Group channels by power supply unit.
            psuAChannels = {};
            psuBChannels = {};
            for i = 1:length(channels)
                [deviceChannel, psuName] = strtok(app.ChannelToDeviceMap(channels{i}), ',');
                psuName = psuName(2:end);
                % Map channel name to channel index (CH1 -> @1).
                switch deviceChannel
                    case {'CH1'}
                        channelIndex = '@1';
                    case {'CH2'}
                        channelIndex = '@2';
                    otherwise
                        error('Unknown channel: %s', logicalChannel);
                end
                if strcmp(psuName, 'PSUA')
                    psuAChannels{end + 1} = channelIndex;
                else
                    psuBChannels{end + 1} = channelIndex;
                end
            end
            % Enable channels on PSU A
            if ~isempty(psuAChannels)
                if isscalar(psuAChannels)
                    % Single channel needs to be enabled.
                    channelList = psuAChannels{1};
                else
                    % Both channels need to be enabled.
                    channelList = '@1,2'; 
                end
                writeline(app.PowerSupplyA, sprintf(':OUTPut:STATe %d,(%s)', state, channelList));
            end

            % Enable channels on PSU B
            if ~isempty(psuBChannels)
                if isscalar(psuBChannels)
                    % Single channel needs to be enabled.
                    channelList = psuBChannels{1}; 
                else
                    % Both channels need to be enabled.
                    channelList = '@1,2';
                end
                writeline(app.PowerSupplyB, sprintf(':OUTPut:STATe %d,%s', state, channelList));
            end
        end


        %% PA TESTING FUNCTIONS
        function runSingleSupplySingleFrequencyPAMeasurement(app)
            try 
                % Single Frequency and Single RF Input Power
                app.inputFrequencySingle = app.StartFrequency.Value * 1E6;
                app.inputPdBmSingle = app.StartPower.Value:app.PowerStep.Value:app.EndPower.Value;

                % Get the number of RF power levels
                numPower = length(app.inputPdBmSingle);
    
                % Initialize output vectors
                app.outputPdBm = zeros(1, numPower);
                app.DCDrainPower = zeros(1, numPower);
    
                % Set PSU values based on the single configured channel
                channel = app.FilledPSUChannels{1};
                PSUsettings = app.ChannelNames.(channel);
                app.setPSUChannels(channel, PSUsettings.Start, PSUsettings.Current);
    
                % Configure the spectrum analyzer
                sweepPoints = app.SweepPointsValueField.Value;
                refLevel = app.ReferenceLevelValueField.Value;
        
                % Reset the spectrum analyzer
                writeline(app.SpectrumAnalyzer, '*RST');
                writeline(app.SpectrumAnalyzer, sprintf(':SENSe:SWEep:POINts %d', sweepPoints));
                writeline(app.SpectrumAnalyzer, sprintf(':DISPlay:WINDow:TRACe:Y:SCALe:RLEVel %g', refLevel));
                writeline(app.SpectrumAnalyzer, sprintf(':FORMat:TRACe:DATA %s,%d', 'REAL', 64));
                writeline(app.SpectrumAnalyzer, sprintf(':FORMat:BORDer %s', 'SWAPped'));
        
                % Enable all configured PSU channels
                app.enablePSUChannels(app.FilledPSUChannels, true);
                pause(app.MeasurementDelayValueField.Value / 2);
                 
                % Configure and enable the signal generator 
                writeline(app.SignalGenerator, '*RST');
                writeline(app.SignalGenerator, sprintf(':OUTPut1:STATe %d', 1));

                % Create a progress dialog
                progressMsg = sprintf('Single frequency measurement');
                d = uiprogressdlg(app.UIFigure, 'Title', 'Measurement Progress', 'Message', progressMsg);
    
                % Single Frequency Measurements
                writeline(app.SignalGenerator, sprintf(':SOURce1:FREQuency:CW %d', app.inputFrequencySingle));
                writeline(app.SpectrumAnalyzer, sprintf(':SENSe:FREQuency:CENTer %g', app.CenterFrequencyValueField.Value * 1E6));
                writeline(app.SpectrumAnalyzer, sprintf(':SENSe:FREQuency:SPAN %g', app.SpanValueField.Value * 1E6));
    
                % Sweep through power levels
                for k = 1:numPower
                    % Update progress
                    d.Value = k/numPower;
                    d.Message = sprintf('Power: %.2f dBm (%d/%d)', app.inputPdBmSingle(k), k, numPower);
    
                    pause(app.MeasurementDelayValueField.Value / 2);
                    [app.outputPdBm(k), app.DCDrainPower(k), app.DCGatePower(k)] = measureRFOutputandDCPower(app, app.inputPdBmSingle(k));
                end
    
                % Close progress dialog
                close(d);
            
                % Turn off the signal generator
                writeline(app.SignalGenerator, sprintf(':SOURce1:POWer:LEVel:IMMediate:AMPLitude %d', -145));
                writeline(app.SignalGenerator, sprintf(':OUTPut1:STATe %d', 0));

                % Disable all PSU channels
                app.enablePSUChannels(app.FilledPSUChannels, false);
    
                % Calculate Gain, DE, and PAE
                [app.PAGain, app.DE, app.PAE] = measureRFParameters(app.inputPdBmSingle, app.outputPdBm, app.DCDrainPower);
            catch ME
                app.displayError(ME);
                % Turn off instruments for safety reasons if an error
                % ocurred.
                app.enablePSUChannels(app.FilledPSUChannels, false);
                writeline(app.SignalGenerator, sprintf(':OUTPut1:STATe %d', 0));
            end
        end


        function runDualSupplySweepFrequenciesPAMeasurement(app)
            try
                % Frequency Sweep and RF Input Power Sweep Values
                app.inputFrequencySweep = app.StartFrequency.Value*1E6:app.FrequencyStep.Value*1E6:app.EndFrequency.Value*1E6;
                app.inputPdBmSweep = app.StartPower.Value:app.PowerStep.Value:app.EndPower.Value;
                
                % Get the number of frequencies and RF power levels
                numFreq = length(app.inputFrequencySweep);
                numPower = length(app.inputPdBmSweep);

                % Initialize output matrices
                app.outputPdBm = zeros(numFreq, numPower);
                app.DCDrainPower = zeros(numFreq, numPower);

                % first set the gate voltage
                gateChannel = app.GateChannels{1};
                GateSettings = app.ChannelNames.(gateChannel);
                app.setPSUChannels(gateChannel, GateSettings.Start, GateSettings.Current);

                drainChannel = app.DrainChannels{1};
                DrainSettings = app.ChannelNames.(drainChannel);
                app.setPSUChannels(drainChannel, DrainSettings.Start, DrainSettings.Current);

                % Enable all configured PSU channels
                app.enablePSUChannels(app.FilledPSUChannels, true);


            catch ME
                app.displayError(ME);
            end
        end

        function runSingleSupplySweepFrequenciesPAMeasurement(app)
            try
                % Frequency Sweep and RF Input Power Sweep Values
                app.inputFrequencySweep = app.StartFrequency.Value*1E6:app.FrequencyStep.Value*1E6:app.EndFrequency.Value*1E6;
                app.inputPdBmSweep = app.StartPower.Value:app.PowerStep.Value:app.EndPower.Value;
                
                % Get the number of frequencies and RF power levels
                numFreq = length(app.inputFrequencySweep);
                numPower = length(app.inputPdBmSweep);

                % Initialize output matrices
                app.outputPdBm = zeros(numFreq, numPower);
                app.DCDrainPower = zeros(numFreq, numPower);
                app.DCGatePower = zeros(numFreq, numPower);

                % Set PSU values based on the single configured channel
                channel = app.FilledPSUChannels{1};
                PSUsettings = app.ChannelNames.(channel);
                app.setPSUChannels(channel, PSUsettings.Start, PSUsettings.Current);

                % Configure the spectrum analyzer
                sweepPoints = app.SweepPointsValueField.Value;
                refLevel = app.ReferenceLevelValueField.Value;

                % Reset the spectrum analyzer
                writeline(app.SpectrumAnalyzer, '*RST');
                writeline(app.SpectrumAnalyzer, sprintf(':SENSe:SWEep:POINts %d', sweepPoints));
                writeline(app.SpectrumAnalyzer, sprintf(':DISPlay:WINDow:TRACe:Y:SCALe:RLEVel %g', refLevel));
                writeline(app.SpectrumAnalyzer, sprintf(':FORMat:TRACe:DATA %s,%d', 'REAL', 64));
                writeline(app.SpectrumAnalyzer, sprintf(':FORMat:BORDer %s', 'SWAPped'));

                % Enable all configured PSU channels
                app.enablePSUChannels(app.FilledPSUChannels, true);
                pause(app.MeasurementDelayValueField.Value / 2);
                 
                % Configure and enable the signal generator 
                writeline(app.SignalGenerator, '*RST');
                writeline(app.SignalGenerator, sprintf(':OUTPut1:STATe %d', 1));

                % Create progress dialog
                progressMsg = sprintf('Frequency sweep: 0/%d', numFreq);
                d = uiprogressdlg(app.UIFigure, 'Title', 'Measurement Progress', 'Message', progressMsg);

                % Frequency Sweep Measurements
                for i = 1:numFreq
                    centerFrequency = app.inputFrequencySweep(i);
                    span = app.inputFrequencySweep(i) / 2;

                    % Update progress
                    d.Value = i/numFreq;
                    d.Message = sprintf('Frequency: %.2f MHz (%d/%d)', centerFrequency/1E6, i, numFreq);

                    % Configure instruments for this frequency
                    writeline(app.SignalGenerator, sprintf(':SOURce1:FREQuency:CW %d', app.inputFrequencySweep(i)));
                    writeline(app.SpectrumAnalyzer, sprintf(':SENSe:FREQuency:CENTer %g', centerFrequency));
                    writeline(app.SpectrumAnalyzer, sprintf(':SENSe:FREQuency:SPAN %g', span));

                    % Sweep through power levels
                    for j = 1:numPower
                        pause(app.MeasurementDelayValueField.Value / 2);
                        [app.outputPdBm(i, j), app.DCDrainPower(i, j), app.DCGatePower(i, j)] = measureRFOutputandDCPower(app, app.inputPdBmSweep(j));
                    end
                end

                % Close progress dialog
                close(d);
            
                % Turn off the signal generator
                writeline(app.SignalGenerator, sprintf(':SOURce1:POWer:LEVel:IMMediate:AMPLitude %d', -145));
                writeline(app.SignalGenerator, sprintf(':OUTPut1:STATe %d', 0));

                % Disable all PSU channels
                app.enablePSUChannels(app.FilledPSUChannels, false);

                % Calculate Gain, DE, and PAE
                [app.PAGain, app.DE, app.PAE] = measureRFParameters(app.inputPdBmSweep, app.outputPdBm, app.DCDrainPower);
            catch ME
                app.displayError(ME);
                % Turn off instruments for safety reasons if an error
                % ocurred.
                app.enablePSUChannels(app.FilledPSUChannels, false);
                writeline(app.SignalGenerator, sprintf(':OUTPut1:STATe %d', 0));
            end
        end


        function peakValues = measureRFParametersPeaks(app)
            % This function calculates the peak values of several RF 
            % parameters (Gain, DE, PAE, Saturation Power, -1dB and -3dB 
            % compression points).
            numFreq = size(app.inputFrequencySweep, 2);
            peakGain = zeros(numFreq, 1);
            peak1dB  = zeros(numFreq, 1);
            peak3dB  = zeros(numFreq, 1);
        
            % % Window size for the moving average
            % windowSize = 3; 
            % smoothedGain = movmean(app.PAGain(i, :), windowSize);
            % peakGain(i) = max(smoothedGain);

            for i = 1:numFreq
                % Get the peak Gain.
                peakGain(i) = max(app.PAGain(i, :));
                % Get the -1dB compression point.
                idx_1dB = find(app.PAGain(i, :) <= (peakGain(i) - 1), 1, 'first');
                if ~isempty(idx_1dB)
                    peak1dB(i) = app.outputPdBm(i, idx_1dB);
                else
                    peak1dB(i) = NaN;
                end
                % Get the -3dB compression point.
                idx_3dB = find(app.PAGain(i, :) <= (peakGain(i) - 3), 1, 'first');
                if ~isempty(idx_3dB)
                    peak3dB(i) = app.outputPdBm(i, idx_3dB);
                else
                    peak3dB(i) = NaN;
                end
            end    
            % Get the max output RF power per frequency.
            saturationPdBm = max(app.outputPdBm, [], 2);
            % Get the max drain efficiency per frequency.
            peakDE  = max(app.DE, [], 2);
            % Get the max power-added efficiency per frequency.
            peakPAE = max(app.PAE, [], 2);
     
            peakValues = {saturationPdBm, peakGain, peakDE, peakPAE, peak1dB', peak3dB'};
        end

        function plotPASingleMeasurement(app)
            % This function plots the data from the single measurement
            % type. The first plot shows input power vs. output power. The
            % second plot shows the DE, PAE, gain, and compression points
            % if found.
            cla(app.SingleGain);

            % Window size for the moving average
            % REMOVE ONCE POWER SUPPLY IS FIXED
            windowSize = 3; 
            smoothedGain = movmean(app.PAGain, windowSize);
            maxGain = max(smoothedGain);

            % Find the 1dB and 3dB saturation points
            idx_1dB = find(smoothedGain <= (maxGain - 1), 1, 'first');
            idx_3dB = find(smoothedGain <= (maxGain - 3), 1, 'first'); 

            % Find the saturation point, the maximum output power
            [saturation_power, saturation_idx] = max(app.outputPdBm);

            % Plot Gain on the left y-axis
            yyaxis(app.SingleGain, 'left');
            disp(app.outputPdBm);
            disp(app.PAGain);

            h1 = plot(app.SingleGain, app.outputPdBm, app.PAGain, 'k-', LineWidth=1.5);
            hold(app.SingleGain, 'on');

            legendEntries = {'Gain'};
            legendHandles = h1;

            % Plot the compression points
            if ~isempty(idx_1dB)
                h4 = plot(app.SingleGain, app.outputPdBm(idx_1dB), app.PAGain(idx_1dB), ...
                    'o', 'MarkerSize', 8, 'MarkerEdgeColor', 'blue', 'MarkerFaceColor', 'blue');
                legendHandles(end+1) = h4; 
                legendEntries{end+1} = 'P_{1dB}';
            end
            if ~isempty(idx_3dB)
                h5 = plot(app.SingleGain, app.outputPdBm(idx_3dB), app.PAGain(idx_3dB), ...
                    'o', 'MarkerSize', 8, 'MarkerEdgeColor', 'red', 'MarkerFaceColor', 'red');
                legendHandles(end+1) = h5; 
                legendEntries{end+1} = 'P_{3dB}'; 
            end
            if ~isempty(saturation_idx)
                h6 = plot(app.SingleGain, saturation_power, app.PAGain(saturation_idx), ...
                    'o', 'MarkerSize', 8, 'MarkerEdgeColor', 'green', 'MarkerFaceColor', 'green');
                legendHandles(end+1) = h6;
                legendEntries{end+1} = 'P_{sat}';
            end

            % Add grid and styling to left axis
            grid(app.SingleGain, 'on');
            ylabel(app.SingleGain, 'Gain (dB)', 'FontWeight', 'bold');
            set(app.SingleGain, 'YColor', 'k');

            % Plot DE and PAE on the right y-axis
            % Plot DE and PAE on the right y-axis with distinct colors and styles
            yyaxis(app.SingleGain, 'right');
            h2 = plot(app.SingleGain, app.outputPdBm, app.DE, '-', 'Color', [0, 0.4470, 0.7410], 'LineWidth', 1.5);
            h3 = plot(app.SingleGain, app.outputPdBm, app.PAE, '--', 'Color', [0.8500, 0.3250, 0.0980], 'LineWidth', 1.5);

            % Add efficiency values to legend
            legendHandles = [legendHandles, h2, h3];
            legendEntries = [legendEntries, {'DE', 'PAE'}];

            ylabel(app.SingleGain, 'Efficiency (%)', 'FontWeight', 'bold');
            set(app.SingleGain, 'YColor', [0.3, 0.3, 0.3]);

            hold(app.SingleGain, 'off');
            xlabel(app.SingleGain, 'Output Power (dBm)', 'FontWeight', 'bold');
            title(app.SingleGain, 'PA Performance Metrics', 'FontWeight', 'bold');
            %title(app.SingleGain, 'Gain, DE, and PAE vs. Output Power');

            legend(app.SingleGain, legendHandles, legendEntries, 'Location', 'best', 'Box', 'on', 'FontSize', 9);
    
            % Add text annotations for key metrics if found
            textPos = 0.02;  % Start position for text (normalized)
            if ~isempty(idx_1dB)
                text(app.SingleGain, 0.05, 0.98-textPos, sprintf('P_{1dB}: %.2f dBm', app.outputPdBm(idx_1dB)), ...
                    'Units', 'normalized', 'FontSize', 8, 'Color', 'blue', 'FontWeight', 'bold', ...
                    'BackgroundColor', [0.95, 0.95, 0.95], 'EdgeColor', 'blue', 'Margin', 2);
                textPos = textPos + 0.06;
            end
            
            if ~isempty(saturation_idx)
                text(app.SingleGain, 0.05, 0.98-textPos, sprintf('P_{sat}: %.2f dBm', saturation_power), ...
                    'Units', 'normalized', 'FontSize', 8, 'Color', 'green', 'FontWeight', 'bold', ...
                    'BackgroundColor', [0.95, 0.95, 0.95], 'EdgeColor', 'green', 'Margin', 2);
                textPos = textPos + 0.06;
            end
            
            % Add maximum efficiency values
            [maxDE, maxDEidx] = max(app.DE);
            [maxPAE, maxPAEidx] = max(app.PAE);
            
            text(app.SingleGain, 0.05, 0.98-textPos, sprintf('Max DE: %.1f%% @ %.2f dBm', maxDE, app.outputPdBm(maxDEidx)), ...
                'Units', 'normalized', 'FontSize', 8, 'Color', [0, 0.4470, 0.7410], 'FontWeight', 'bold', ...
                'BackgroundColor', [0.95, 0.95, 0.95], 'EdgeColor', [0, 0.4470, 0.7410], 'Margin', 2);
            textPos = textPos + 0.06;
            
            text(app.SingleGain, 0.05, 0.98-textPos, sprintf('Max PAE: %.1f%% @ %.2f dBm', maxPAE, app.outputPdBm(maxPAEidx)), ...
                'Units', 'normalized', 'FontSize', 8, 'Color', [0.8500, 0.3250, 0.0980], 'FontWeight', 'bold', ...
                'BackgroundColor', [0.95, 0.95, 0.95], 'EdgeColor', [0.8500, 0.3250, 0.0980], 'Margin', 2);
             
        end

        function plotPASweepMeasurement(app)
            % This function plots the data from the sweep measurement type.
            % The first plots shows gain vs. output power. Peak DE, PAE,
            % and gain are also plotted for each frequency. Finally the
            % saturation, and compression points are plotted. If no curve
            % appears for the compression points then there was a frequency
            % with no compression.

            % Clear existing plots.
            cla(app.GainvsOutputPowerPlot);
            cla(app.PeakGainPlot);
            cla(app.PeakDEPAEPlot);
            cla(app.CompressionPointsPlot);

            % Get the number of frequencies measured.
            numFreqs = size(app.outputPdBm, 1);

            % Get peak values from RF measurements.
            peakValues = app.measureRFParametersPeaks();          
            
            % Gain Vs. RF Output Power
            hold(app.GainvsOutputPowerPlot, 'on'); 
            for i = 1:numFreqs
                plot(app.GainvsOutputPowerPlot, app.outputPdBm(i, :), app.PAGain(i, :));
            end
            hold(app.GainvsOutputPowerPlot, 'off');
            title(app.GainvsOutputPowerPlot, 'Gain vs. Output Power');
            xlabel(app.GainvsOutputPowerPlot, 'Output Power (dBm)');
            ylabel(app.GainvsOutputPowerPlot, 'Gain (dB)');
            
            % Peak Gain Vs. Frequency
            plot(app.PeakGainPlot, app.inputFrequencySweep / 1E9, peakValues{2}, 'blue');
            %plot(app.PeakGainPlot, app.inputFrequencySweep(1:size(app.outputPdBm, 1))/1E9, peakValues{2}(1:size(app.outputPdBm, 1)), 'blue');
            title(app.PeakGainPlot, 'Peak Gain');
            xlabel(app.PeakGainPlot, 'Frequency (GHz)');
            ylabel(app.PeakGainPlot, 'Gain (dB)');
            
            % Peak DE & Peak PAE Vs. Frequency
            hold(app.PeakDEPAEPlot, 'on');

            %h1 = plot(app.PeakDEPAEPlot, app.inputFrequencySweep(1:size(app.outputPdBm, 1))/1E9, peakValues{3}(1:size(app.outputPdBm, 1)), 'blue');
            %h2 = plot(app.PeakDEPAEPlot, app.inputFrequencySweep(1:size(app.outputPdBm, 1))/1E9, peakValues{4}(1:size(app.outputPdBm, 1)), 'red');

            h1 = plot(app.PeakDEPAEPlot, app.inputFrequencySweep / 1E9, peakValues{3}, 'b-o', 'LineWidth', 1.5);
            h2 = plot(app.PeakDEPAEPlot, app.inputFrequencySweep / 1E9, peakValues{4}, 'r-o', 'LineWidth', 1.5);

            hold(app.PeakDEPAEPlot, 'off');
            title(app.PeakDEPAEPlot, 'Peak DE and PAE');
            xlabel(app.PeakDEPAEPlot, 'Frequency (GHz)');
            ylabel(app.PeakDEPAEPlot, 'Efficiency (%)');
            legend(app.PeakDEPAEPlot, [h1, h2], {'DE', 'PAE'}, 'Location', 'best');

            % Saturation Power and Compression Points
            plotColors = {'black', 'red', 'blue'};
            legendLabels = {'P_{sat}', 'P_{-1dB}', 'P_{-3dB}'};
            usedLegendLabels = {};
            plotHandles = [];
            xData = cell(1, 3);
            yData = cell(1, 3);

            for i = 1:numFreqs
                % Index 1 corresponds to saturation power.
                if ~isnan(peakValues{1}(i))
                    xData{1} = [xData{1}, app.inputFrequencySweep(i) / 1E9];
                    yData{1} = [yData{1}, peakValues{1}(i)];
                end
                % Index 5 corresponds to -1dB compression point.
                if ~isnan(peakValues{5}(i))
                    xData{2} = [xData{2}, app.inputFrequencySweep(i) / 1E9];
                    yData{2} = [yData{2}, peakValues{5}(i)];
                end
                % Index 6 corresponds to -3dB compression point.
                if ~isnan(peakValues{6}(i))
                    xData{3} = [xData{3}, app.inputFrequencySweep(i) / 1E9];
                    yData{3} = [yData{3}, peakValues{6}(i)];
                end
            end

            hold(app.CompressionPointsPlot, 'on'); 

            for idx = 1:3
                if ~isempty(xData{idx})
                    h = plot(app.CompressionPointsPlot, xData{idx}, yData{idx}, [plotColors{idx}, '-o'], 'LineWidth', 1.5);
                    plotHandles = [plotHandles, h];
                    usedLegendLabels{end + 1} = legendLabels{idx};
                end
            end

            ylabel(app.CompressionPointsPlot, 'Output Power (dBm)');
            xlabel(app.CompressionPointsPlot, 'Frequency (GHz)');
            title(app.CompressionPointsPlot, 'Saturation Power and Compression Points');

            if ~isempty(plotHandles)
                legend(app.CompressionPointsPlot, plotHandles, usedLegendLabels, 'Location', 'best');
            end
            hold(app.CompressionPointsPlot, 'off');

            %improveAxesAppearance(app.GainvsOutputPowerPlot, 'YYAxis', 'false', 'LineThickness', 2.5);
            %improveAxesAppearance(app.PeakGainPlot, 'YYAxis', 'false', 'LineThickness', 2.5);
            %improveAxesAppearance(app.PeakDEPAEPlot, 'YYAxis', 'false', 'LineThickness', 2.5);
            %improveAxesAppearance(app.CompressionPointsPlot, 'YYAxis', 'false', 'LineThickness', 2.5);
        end

        function getBoresightGain(app, startFrequency, endFrequency, sweepPoints)
            try
                [SParameters, VNAFreqs] = measureSParameters(app.VNA, 2, startFrequency, endFrequency, sweepPoints);
                BoresightGain_dBi = measureAntennaGain(VNAFreqs, SParameters{2}, app.setupSpacing);
                combinedData = [double(VNAFreqs)', double(BoresightGain_dBi)', double(SParameters{1})'];
                combinedNames = {'Frequency (Hz)', 'Gain (dBi)', 'Return Loss (dB)'};
                saveData(combinedData, combinedNames, false);
            catch ME
                app.displayError(ME);
            end
        end

        function get2DAntennaGain(app, startFrequency, endFrequency, sweepPoints) 
            app.StopRequested = false;

            if ~isempty(app.ReferenceGainFile)
                ReferenceFreqs = app.ReferenceGainFile.FrequencyHz;
                ReferenceGain = app.ReferenceGainFile.GaindBi;
            end

            if isempty(app.TableSpeedSlider.Value)
                error('Table Speed cannot be empty.');
            elseif isempty(app.TableStartAngle.Value)
                error('Table Start Angle cannot be empty.');
            elseif isempty(app.TableStepAngle.Value)
                error('Table Step Angle cannot be empty.');
            elseif isempty(app.TableEndAngle.Value)
                error('Table End Angle cannot be empty.');
            else
                tableSpeed = app.TableSpeedSlider.Value;
                tableAngles = app.TableStartAngle.Value:app.TableStepAngle.Value:app.TableEndAngle.Value;
                arraySize = sweepPoints * length(tableAngles);
            end
            
            app.S11 = zeros(1, arraySize);
            app.S21 = zeros(1, arraySize);
            app.S22 = zeros(1, arraySize); 
            app.AzimuthAngles = zeros(1, arraySize);
            app.TestFrequencies = zeros(1, arraySize);
            app.AntennaGain_dBi = zeros(1, arraySize);
      
            try
                % Set speed of the turntable
                writeline(app.EMCenter, sprintf('1A:SPEED %d', tableSpeed));

                for i = 1:length(tableAngles)
                    dataPts = (i-1)*sweepPoints + (1:sweepPoints);
                    adjustedAzimuthAngle = mod(tableAngles(i), 360);

                    % Move table to specified angle
                    writeline(app.EMCenter, sprintf('1A:SK %d', adjustedAzimuthAngle));
                    status = writeread(app.EMCenter,"1A:*OPC?");
                    status = str2double(strtrim(status));
                    while status ~= 1
                        drawnow;

                        % Check if the user requested the test measurement to stop
                        if app.StopRequested
                            writeline(app.EMCenter, '1A:ST');
                            pause(1);
                            break;
                        end
                        
                        status = writeread(app.EMCenter,"1A:*OPC?");
                        status = str2double(strtrim(status));
                    end

                    % Exit the outer loop as well if stop was requested
                    if app.StopRequested
                        break;
                    end

                    % Get S-Parameters and Frequencies from VNA
                    [SParameters, VNAFrequencies] = measureSParameters(app.VNA, 2, startFrequency, endFrequency, sweepPoints); 

                    if ~isempty(app.ReferenceGainFile)
                        Gain_dBi = measureAntennaGain(VNAFrequencies, SParameters{2}, app.setupSpacing, ReferenceGain, ReferenceFreqs);
                    else
                        Gain_dBi = measureAntennaGain(VNAFrequencies, SParameters{2}, app.setupSpacing);
                    end

                    app.AzimuthAngles(dataPts) = tableAngles(i);
                    app.TestFrequencies(dataPts) = VNAFrequencies;
                    app.S11(dataPts) = SParameters{1};
                    app.S21(dataPts) = SParameters{2};
                    app.S22(dataPts) = SParameters{3};
                    app.AntennaGain_dBi(dataPts) = Gain_dBi;
                end

                % Return turntable to starting position
                writeline(app.EMCenter, sprintf('1A:SK %d', 0));

                % If the measurement was not stopped 
                if ~app.StopRequested
                    combinedData = [double(app.AzimuthAngles)', double(app.TestFrequencies)', double(app.AntennaGain_dBi)', double(app.S11)'];
                    combinedNames = {'Azimuth Angles (deg)', 'Frequency (Hz)', 'Gain (dBi)', 'Return Loss (dB)'};
            
                    % Check if the dataset exceeds the Excel row limit
                    passedLimit = length(app.AzimuthAngles) > app.EXCEL_MAX_ROWS;
            
                    % Save the measurement data
                    saveData(combinedData, combinedNames, passedLimit);
                end
            catch ME
                app.displayError(ME);
            end
        end

        function resetAntennaMeasurementProperties(app)
            app.AzimuthAngles = [];
            app.TestFrequencies = [];
            app.S11 = [];
            app.S21 = [];
            app.S22 = [];
            app.AntennaGain_dBi = [];
        end

        function plotAntenna2DMeasurement(app, sweepPoints)            
            cla(app.GainvsFrequency2D);
            cla(app.ReturnLoss2D);
            cla(app.GainvsAngle2D);
            cla(app.RadiationPlot2D);

            % Specified Angle Plotting  
            angleIndices = (app.angleRange*sweepPoints+1):((app.angleRange+1)*sweepPoints);
            angleGain = app.antennaGain(angleIndices);
            angleReturnLoss = app.antennaRL(angleIndices);

            % Specified Frequency Plotting
            frequencyIndices = app.frequencyRange:sweepPoints:length(app.antennaGain);
            frequencyGain = app.antennaGain(frequencyIndices);

            %disp(size(angleGain))
            %disp(size(app.AzimuthAngles))
            %disp(size(frequencyGain))

            % Antenna Gain vs. Frequency, at specified angle
            plot(app.GainvsFrequency2D, app.sweepFrequencies/1E9, angleGain);
            title(app.GainvsFrequency2D, sprintf('Gain vs. Frequency at %1.0f^{\\circ}', str2double(app.selectedAngle)));
            xlabel(app.GainvsFrequency2D, 'Frequency (GHz)');
            ylabel(app.GainvsFrequency2D, 'Gain (dBi)');
            app.setAxisLimits(app.GainvsFrequency2D, angleGain);

            % Antenna Gains vs. Angles, at specified frequency.
            plot(app.GainvsAngle2D, app.azimuthAngles, frequencyGain);
            title(app.GainvsAngle2D, 'Gain vs. Angle');
            xlabel(app.GainvsAngle2D, 'Angle (degrees)');
            ylabel(app.GainvsAngle2D, 'Gain (dBi)');
            xlim(app.GainvsAngle2D, [-180 180]);
            app.setAxisLimits(app.GainvsAngle2D, frequencyGain);

            % Return Loss (dB) Plot
            plot(app.ReturnLoss2D, app.sweepFrequencies/1E9, angleReturnLoss);
            title(app.ReturnLoss2D, sprintf('Return Loss at %1.0f^{\\circ}', str2double(app.selectedAngle)));
            xlabel(app.ReturnLoss2D, 'Frequency (GHz)');
            ylabel(app.ReturnLoss2D, 'RL (dB)');

            % 2D Radiation Pattern Plot
            polarplot(app.RadiationPlot2D, deg2rad(app.azimuthAngles), frequencyGain);
            title(app.RadiationPlot2D, sprintf('Radiation Pattern at %1.3f GHz', str2double(app.selectedFrequency)));
            thetalim(app.RadiationPlot2D, [0 360]);
            app.setAxisLimits(app.RadiationPlot2D, frequencyGain);

            improveAxesAppearance(app.GainvsFrequency2D, 'LineThickness', 2);
            improveAxesAppearance(app.ReturnLoss2D, 'LineThickness', 2);
            improveAxesAppearance(app.GainvsAngle2D, 'LineThickness', 2);
            %improveAxesAppearance(app.RadiationPlot2D);
        end

        
        %% HELPER FUNCTIONS
        function setAxisLimits(~, axisHandle, data)
            minData = min(data);
            maxData = max(data);
            margin = (maxData - minData) * 0.1;

            if isa(axisHandle, 'matlab.ui.control.UIAxes')
                if margin == 0
                    margin = 0.1;
                end
                ylim(axisHandle, [minData - margin, maxData + margin]);              
            elseif isa(axisHandle, 'matlab.graphics.axis.PolarAxes')
                if margin == 0
                    margin = 1; 
                end
                rlim(axisHandle, [minData - margin, maxData + margin]);
            end
        end

        function frequencyLabel = formatFrequency(~, frequency)
            % This function takes in a frequency value and returns it in
            % scientific notation for plotting and viewing purposes.
            if frequency >= 1e9
                frequencyLabel = sprintf('%.2f GHz', frequency / 1e9);
            elseif frequency >= 1e6
                frequencyLabel = sprintf('%.2f MHz', frequency / 1e6);
            elseif frequency >= 1e3
                frequencyLabel = sprintf('%.2f kHz', frequency / 1e3);
            else
                frequencyLabel = sprintf('%.2f Hz', frequency);
            end     
        end
        
        function displayError(app, ME)
            % This function displays a detailed error message to the GUI 
            % alert box. It takes in an MException object (ME), extracts
            % the error details (message, type, and stack trace), 
            % and formats them into a readable string for the user.
            detailedErrorMessage = sprintf('Error: %s\n\nType of Error: %s\n\nStack Trace:\n', ME.message, ME.identifier);
            for k = 1:length(ME.stack)
                detailedErrorMessage = sprintf('%sIn file: %s\nFunction: %s\nLine: %d\n\n', ...
                    detailedErrorMessage, ME.stack(k).file, ME.stack(k).name, ME.stack(k).line);
            end
            uialert(app.UIFigure, detailedErrorMessage, 'Error', 'Icon', 'warning');
        end
    end
    
    methods (Access = public)

        function waitForInstrument(~, Instrument)
            % Check if the instrument operation is complete.
            timeout = 5; % Adjust the timeout duration as needed (seconds)
            tic; 
            while true
                % Query the instrument for its operation status
                status = sscanf(writeread(Instrument, '*OPC?'), '%d');
                % Check if the instrument is ready (status == 1) or if the timeout has been exceeded
                if (status == 1 || toc > timeout)
                    break; 
                end 
                pause(app.MeasurementDelayValueField.Value); % Pause for 1 second before checking again
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Handle Application settings
            app.UIFigure.WindowState = 'maximized';
            drawnow;
            
            % Handle PA testing settings
            app.PSUMode = 'Single Supply';
            app.ChannelNames = struct();
            app.ChannelToDeviceMap = containers.Map({'A1', 'A2', 'B1', 'B2'}, {'CH1,PSUA', 'CH2,PSUA', 'CH1,PSUB', 'CH2,PSUB'});
            app.TypeFreqMeasurement = 'Single Frequency';
            app.SpanValueField.Enable = 'on';
            app.CenterFrequencyValueField.Enable = 'on';
            app.EndFrequency.Enable = 'off';
            app.FrequencyStep.Enable = 'off';

            app.SingleSweepSwitch.Value = 'Single';
            app.SupplyCurrentValueField.Visible = 'on';
            app.SupplyVoltageStartValueField.Visible = 'on';
            app.SupplyVoltageStopValueField.Visible = 'off';
            app.SupplyVoltageStepValueField.Visible = 'off';

            % Handle Antenna testing settings
            app.RadiationPlot2D = polaraxes(app.GridLayout3);
            app.RadiationPlot2D.Units = "pixels";
            app.RadiationPlot2D.Layout.Row = 2;
            app.RadiationPlot2D.Layout.Column = 2;  
            app.RadiationPlot2D.Title.String = '2D Radiation Pattern';
            app.AntennaCurrentSpacing.Enable = 'off';
            app.LinearSliderCurrentPosition.Enable = 'off';
            app.SpeedPresetDropDown.Enable = 'off';
            app.MovetoPositionButton.Enable = 'off';
            app.LinearSliderNewPosition.Enable = 'off';
        end

        % Button pushed function: PAScanButton, PAScanButton_2
        function PAScanResources(app, event)
            instrumentNames = {'PowerSupplyA', 'PowerSupplyB', 'SignalGenerator', 'SpectrumAnalyzer'};
            app.newgetInstrumentResources(instrumentNames)
        end

        % Button pushed function: PAConnectButton
        function PAConnectInstruments(app, event)
            % Code waits for user to select an address in the dropdown menu.
            % It gets that resource and attempts to connect to it.
            instrumentNames = {'PowerSupplyA', 'PowerSupplyB', 'SignalGenerator', 'SpectrumAnalyzer'};
            %instrumentResources = {app.PowerSupplyADropDown.Value,app.PowerSupplyBDropDown.Value, app.SignalGeneratorDropDown.Value, app.SpectrumAnalyzerDropDown.Value};

            % TO TEST ALL THREE INSTRUMENTS
            % E36234A
            %instrumentResources = {'None', 'TCPIP0::192.168.3.173::5025::SOCKET', 'TCPIP0::192.168.3.52::hislip0::INSTR', 'TCPIP0::192.168.1.173::inst0::INSTR'};
            % E36233A
            instrumentResources = {'TCPIP0::192.168.2.16::5025::SOCKET', 'None', 'TCPIP0::192.168.3.52::hislip0::INSTR', 'TCPIP0::192.168.1.173::inst0::INSTR'};
            % TO TEST ALL FOUR INSTRUMENTS
            % E36233A, E36234A, SMW200A, N9000B
            %instrumentResources = {'TCPIP0::192.168.2.16::5025::SOCKET', 'TCPIP0::192.168.3.173::5025::SOCKET', 'TCPIP0::192.168.3.52::hislip0::INSTR', 'TCPIP0::192.168.1.173::inst0::INSTR'};
            app.newconnectInstrument(instrumentNames, instrumentResources);    
        end

        % Value changed function: PowerSupplyModeDropDown
        function PSUChangeMode(app, event)
            newMode = app.PowerSupplyModeDropDown.Value; 
            % The default value of app.PSUMode is 'Single Supply' for
            % safety reasons.
            if isempty(newMode)
                app.PSUMode = 'Single Supply';
            else
                app.PSUMode = newMode;
            end
            % If the mode changes to 'No Supply' all input parameters to
            % the PSU are disabled to the user.
            switch newMode
                case 'No Supply'
                    app.SupplyCurrentValueField.Enable = 'off';
                    app.SupplyVoltageStartValueField.Enable = 'off';
                    app.SupplyVoltageStopValueField.Enable = 'off';
                    app.SupplyVoltageStepValueField.Enable = 'off';
                otherwise
                    app.SupplyCurrentValueField.Enable = 'on';
                    app.SupplyVoltageStartValueField.Enable = 'on';
                    app.SupplyVoltageStopValueField.Enable = 'on';
                    app.SupplyVoltageStepValueField.Enable = 'on';
            end
            app.PSUChannelDropDownOpening();
        end

        % Value changed function: PowerSupplyChannelDropDown
        function PSUChangeChannel(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;

            if isempty(selectedChannel) || ~ischar(selectedChannel)
                return;
            end

            % Debugging
            disp(['Selected channel: ' selectedChannel]);
            disp(['Existing channels: ' strjoin(fieldnames(app.ChannelNames), ', ')]);
            disp(['Is field exists: ' num2str(isfield(app.ChannelNames, selectedChannel))]);
    
            % If the channel is new, initialize its values
            if ~isfield(app.ChannelNames, selectedChannel)
                disp('Initializing channel with zeros');
                app.ChannelNames.(selectedChannel) = struct('Current', 0, 'Start', 0, 'Stop', 0, 'Step', 0, 'Mode', 'Single');

                % New channel is drain by default
                if ~ismember(selectedChannel, [app.DrainChannels, app.GateChannels])
                    app.DrainChannels{end+1} = selectedChannel;
                    app.DrainGateSwitch.Value = 'Drain';
                end
            else
                disp(['Current values: Current=' num2str(app.ChannelNames.(selectedChannel).Current) ...
                      ' Start=' num2str(app.ChannelNames.(selectedChannel).Start) ...
                      ' Stop=' num2str(app.ChannelNames.(selectedChannel).Stop) ...
                      ' Step=' num2str(app.ChannelNames.(selectedChannel).Step)]);

                % Set the mode switch based on saved channel mode
                if isfield(app.ChannelNames.(selectedChannel), 'Mode')
                    app.SingleSweepSwitch.Value = app.ChannelNames.(selectedChannel).Mode;
                else
                    app.SingleSweepSwitch.Value = 'Single';  % Default to single if mode not set
                    app.ChannelNames.(selectedChannel).Mode = 'Single';
                end

                % Update DrainGateSwitch based on channel designation
                if any(strcmp(selectedChannel, app.DrainChannels))
                    app.DrainGateSwitch.Value = 'Drain';
                elseif any(strcmp(selectedChannel, app.GateChannels))
                    app.DrainGateSwitch.Value = 'Gate';
                else
                    % Default to Drain if not in either list
                    app.DrainGateSwitch.Value = 'Drain';
                    app.DrainChannels{end+1} = selectedChannel;
                end

                % Call the SingleSweepSwitch function to update UI visibility
                SingleSweepSwitchValueChanged(app, []);
            end

            % Load saved values into UI edit fields
            app.SupplyCurrentValueField.Value = app.ChannelNames.(selectedChannel).Current;
            app.SupplyVoltageStartValueField.Value = app.ChannelNames.(selectedChannel).Start;
            app.SupplyVoltageStopValueField.Value = app.ChannelNames.(selectedChannel).Stop;
            app.SupplyVoltageStepValueField.Value = app.ChannelNames.(selectedChannel).Step;
        end

        % Drop down opening function: PowerSupplyChannelDropDown
        function PSUChannelDropDownOpening(app, event)
            % Check which PSUs are connected
            hasPSUA = ~isempty(app.PowerSupplyA);
            hasPSUB = ~isempty(app.PowerSupplyB);

            if ~hasPSUA && ~hasPSUB
                items = {''};
                app.PowerSupplyChannelDropDown.Items = items;
                return;
            end

            items = {};
            if hasPSUA
                items = [items {'A1', 'A2'}];
            end
            if hasPSUB
                items = [items {'B1', 'B2'}];
            end

            currentValue = app.PowerSupplyChannelDropDown.Value;
            
            % Populate power supply dropdown with items
            app.PowerSupplyChannelDropDown.Items = items;
            
            % Make sure there's at least one item and set to first item if current is invalid
            if ~isempty(items)
                if isempty(currentValue) || ~ismember(currentValue, items)
                    app.PowerSupplyChannelDropDown.Value = items{1};
                else
                    app.PowerSupplyChannelDropDown.Value = currentValue;
                end
                % Change the channel if there is a valid selection
                if ~isempty(app.PowerSupplyChannelDropDown.Value)
                    PSUChangeChannel(app, []);
                end
            end
        end

        % Value changed function: SupplyCurrentValueField
        function PSUSetLimitingCurrent(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Current = app.SupplyCurrentValueField.Value;
            end 
            app.populatePSUChannels();        
        end

        % Value changed function: SupplyVoltageStartValueField
        function PSUSetStartVoltage(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;
            disp(selectedChannel)
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Start = app.SupplyVoltageStartValueField.Value;
            end   
            app.populatePSUChannels();
        end

        % Value changed function: SupplyVoltageStopValueField
        function PSUSetStopVoltage(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Stop = app.SupplyVoltageStopValueField.Value;
            end 
            app.populatePSUChannels();
        end

        % Value changed function: SupplyVoltageStepValueField
        function PSUSetStepVoltage(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Step = app.SupplyVoltageStepValueField.Value;
            end 
            app.populatePSUChannels();
        end

        % Value changed function: MeasurementTypeDropDown
        function PAMeasurementType(app, event)
            app.TypeFreqMeasurement = app.MeasurementTypeDropDown.Value;
            if strcmp(app.TypeFreqMeasurement, 'Sweep Frequencies')
                app.SpanValueField.Enable = 'off';
                app.CenterFrequencyValueField.Enable = 'off';
                app.EndFrequency.Enable = 'on';
                app.FrequencyStep.Enable = 'on';
            elseif strcmp(app.TypeFreqMeasurement, 'Single Frequency')
                app.SpanValueField.Enable = 'on';
                app.CenterFrequencyValueField.Enable = 'on';
                app.EndFrequency.Enable = 'off';
                app.FrequencyStep.Enable = 'off';
            end
        end

        % Button pushed function: StartPAMeasurement
        function PAStartMeasurement(app, event)
            freqType = app.TypeFreqMeasurement;
            PSUmode = app.PSUMode;

            % Validate configuration first
            if ~app.validatePSUChannels()
                disp('Validation failed');
                return;
            end

            if strcmp(freqType, 'Single Frequency')  
                % single frequency and single supply
                app.runSingleSupplySingleFrequencyPAMeasurement();
            elseif strcmp(freqType, 'Sweep Frequencies') && strcmp(PSUmode, 'Single Supply')
                % Intended for a single drain supply and multiple
                % frequencies.
                app.runSingleSupplySweepFrequenciesPAMeasurement();
            elseif strcmp(freqType, 'Sweep Frequencies') && strcmp(PSUmode, 'Dual Supply')
                % Intended for a single drain supply, a single gate bias, 
                % and multiple frequencies.
                app.runDualSupplySweepFrequenciesPAMeasurement();
            end
        end

        % Button pushed function: PlotPAData
        function PAPlotMeasurement(app, event)
            if strcmp(app.TypeFreqMeasurement, 'Sweep Frequencies')
                app.plotPASweepMeasurement();
            elseif strcmp(app.TypeFreqMeasurement, 'Single Frequency')
                app.plotPASingleMeasurement();
            end
            %app.PlotPAData.Enable = 'off';
        end

        % Button pushed function: SavePAData
        function PASaveData(app, event)
            if strcmp(app.TypeFreqMeasurement, 'Sweep Frequencies')
                numFreq = length(app.inputFrequencySweep);
                maxLength = max(length(app.inputPdBmSweep));
                paddedFreq = NaN(maxLength, numFreq);
                for i = 1:numFreq
                    paddedFreq(1,i) = app.inputFrequencySweep(i);
                end
                transpose(paddedFreq);
    
                combinedData = [paddedFreq, app.inputPdBmSweep', app.outputPdBm', app.DCDrainPower', app.PAGain', app.DE', app.PAE'];
                combinedNames = cell(1, size(combinedData, 2));
                parameterNames = {'Frequency (Hz)', 'Output Power (dBm)', 'DC Power (W)', 'Gain (dB)', 'Drain Efficiency (%)', 'Power-Added Efficiency (%)'};
    
                for i = 1:numFreq
                    combinedNames{i} = sprintf('%s [%d]', parameterNames{1}, i);
                end
    
                combinedNames{numFreq + 1} = 'Input Power (dBm)';
    
                index = numFreq + 2;
    
                for k = 2:numel(parameterNames)
                    for j = 1:numFreq
                        combinedNames{index} = sprintf('%s [%d]', parameterNames{k}, j);
                        index = index + 1;
                    end
                end
            elseif strcmp(app.TypeFreqMeasurement, 'Single Frequency')
                maxLength = max(length(app.inputPdBmSingle));
                paddedFreq = nan(maxLength, 1);
                paddedFreq(1:length(app.inputFrequencySingle)) = app.inputFrequencySingle;
                transpose(paddedFreq)
                
                combinedData = [paddedFreq, app.inputPdBmSingle', app.outputPdBm', app.DCDrainPower', app.PAGain', app.DE', app.PAE'];
                combinedNames = {'Frequency (Hz)', 'Input Power (dBm)', 'Output Power (dBm)', 'DC Power (W)', 'Gain (dB)', 'Drain Efficiency (%)', 'Power-Added Efficiency (%)'};
            end

            try
                saveData(combinedData, combinedNames);
            catch ME
                 uialert(app.UIFigure, 'Failed to save data.', 'Error', 'Icon', 'warning');
            end 
        end

        % Button pushed function: LoadPAData
        function PALoadData(app, event)
            try
                disp(app.TypeFreqMeasurement)
                combinedData = loadData('PA');
                if ~isempty(combinedData) 
                    if strcmp(app.TypeFreqMeasurement, 'Sweep Frequencies')
                        app.inputFrequencySweep = combinedData.FrequencyHz';
                        app.inputPdBmSweep = combinedData.InputPowerdBm';
                        app.outputPdBm = combinedData.OutputPowerdBm';
                        app.DCDrainPower = combinedData.DCPowerW';
                        app.PAGain = combinedData.GaindB';
                        app.DE = combinedData.DrainEfficiency';
                        app.PAE = combinedData.PowerAddedEfficiency';
                    elseif strcmp(app.TypeFreqMeasurement, 'Single Frequency')
                        app.inputFrequencySingle = unique(combinedData.FrequencyHz)';
                        app.inputPdBmSingle = combinedData.InputPowerdBm';
                        app.outputPdBm = combinedData.OutputPowerdBm';
                        app.DCDrainPower = combinedData.DCPowerW';
                        app.PAGain = combinedData.GaindB';
                        app.DE = combinedData.DrainEfficiency';
                        app.PAE = combinedData.PowerAddedEfficiency';
                    end
                end
            catch ME
                uialert(app.UIFigure, 'Failed to load data.', 'Error', 'Icon', 'warning');
            end
        end

        % Value changed function: SingleSweepSwitch
        function SingleSweepSwitchValueChanged(app, event)
            value = app.SingleSweepSwitch.Value;
            selectedChannel = app.PowerSupplyChannelDropDown.Value;

            if isempty(selectedChannel)
                return;
            end

            % Update GUI based on single PSU value or sweep PSU values.
            if strcmp(value, 'Single')
                app.SupplyCurrentValueField.Visible = 'on';
                app.SupplyVoltageStartValueField.Visible = 'on';
                app.SupplyVoltageStopValueField.Visible = 'off';
                app.SupplyVoltageStepValueField.Visible = 'off';
            elseif strcmp(value, 'Sweep')
                app.SupplyCurrentValueField.Visible = 'on';
                app.SupplyVoltageStartValueField.Visible = 'on';
                app.SupplyVoltageStopValueField.Visible = 'on';
                app.SupplyVoltageStepValueField.Visible = 'on';
            end
            
            % Store the mode selection into the channel configuration.
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Mode = value;
            end

            % Debugging Output
            disp(['Set channel ' selectedChannel ' to mode: ' value]);
        end

        % Value changed function: DrainGateSwitch
        function DrainGateSwitchValueChanged(app, event)
            value = app.DrainGateSwitch.Value;
            selectedChannel = app.PowerSupplyChannelDropDown.Value;

            if isempty(selectedChannel)
                return;
            end

            if strcmp(value, 'Drain')
                app.DrainChannels = unique([app.DrainChannels, {selectedChannel}]);
                app.GateChannels = setdiff(app.GateChannels, {selectedChannel});
            elseif strcmp(value, 'Gate')
                app.GateChannels = unique([app.GateChannels, {selectedChannel}]);
                app.DrainChannels = setdiff(app.DrainChannels, {selectedChannel});
            end

            % Debugging output
            disp(['Drain Channels: ', strjoin(app.DrainChannels, ', ')]);
            disp(['Gate Channels: ', strjoin(app.GateChannels, ', ')]);
        end

        % Button pushed function: AntennaScanButton
        function AntennaScanResources(app, event)
            % This function scans for available VISA resources (instruments connected via VISA protocol) and populates the dropdown 
            % menus for user selection. This functionality is triggered by the user pressing the Scan button in the antenna section 
            % of the application.

            instrumentNames = {'VNA', 'EMCenter'};
            try
                app.getInstrumentResources(instrumentNames)
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: AntennaConnectButton
        function AntennaConnectInstruments(app, event)
            % This function handles the connection of the application to the antenna measurement instruments
            % (Vector Network Analyzer (VNA) and EMCenter). The user selects the appropriate instrument addresses via a dropdown menu, 
            % and the function uses these selections to establish connections. If successful, the instruments are ready for subsequent 
            % commands and data acquisition.      
            
            instrumentNames = {'VNA', 'EMCenter'};
            try
                %instrumentResources = {'TCPIP0::192.168.1.113::hislip0::INSTR', 'TCPIP0::192.168.2.150::inst0::INSTR'};
                instrumentResources = {app.VNADropDown.Value, app.EMCenterDropDown.Value};
                app.connectInstrument(instrumentNames, instrumentResources);   
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: ConnectButton
        function LinearSliderConnect(app, event)
            % This function establishes a connection between the application and the linear slider hardware via TCP/IP. It ensures that 
            % a connection is made only if none exists already, it retrieves the current slider position, and updates the relevant GUI components
            % to reflect the connection status and enable user interactions.

            if isempty(app.LinearSlider)
                try 
                    % Initialize TCP client connection
                    app.LinearSlider = tcpclient('192.168.0.100', 1206);
                    app.LinearSlider.ByteOrder = 'little-endian';

                    % Get current position from Linear Slider
                    app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                    app.AntennaCurrentSpacing.Value = app.LINEAR_SLIDER_RANGE + app.offsetSpacing - app.LinearSliderCurrentPosition.Value/100;
                    app.setupSpacing = app.AntennaCurrentSpacing.Value;

                    % Enable Linear Slider GUI interface
                    app.AntennaCurrentSpacing.Enable = 'on';
                    app.LinearSliderCurrentPosition.Enable = 'on';
                    app.SpeedPresetDropDown.Enable = 'on';
                    app.MovetoPositionButton.Enable = 'on';
                    app.LinearSliderNewPosition.Enable = 'on';
                catch ME
                    app.displayError(ME);
                end
            end
        end

        % Value changed function: SpeedPresetDropDown
        function LinearSliderChangeSpeed(app, event)
            % This function updates the movement speed of the linear slider by sending a speed preset command to the hardware. The speed 
            % settings are predefined as presets, with values ranging from 1 (slowest) to 8 (fastest). The selected speed preset is read 
            % from the application's dropdown menu and applied directly to the slider.
            
            try 
                % Send command to linear slider to change its speed
                speedPreset = str2double(app.SpeedPresetDropDown.Value);
                writeline(app.LinearSlider, sprintf('AXIS1:S%d', speedPreset));
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: MovetoPositionButton
        function LinearSliderChangePosition(app, event)
            % This function updates the position the linear slider and dynamically adjusts associated parameters based on the slider's movement. 
            % It continuously monitors the slider's status to ensure the desired position is reached and updates the application's calculated 
            % spacing values in real-time.
            
            try
                % Send command to linear slider to move to the specified position
                writeline(app.LinearSlider, sprintf('AXIS1:SK %d', app.LinearSliderNewPosition.Value));
                motion = writeread(app.LinearSlider, 'AXIS1:DIR?');
                
                % Continuously update the setup spacing until motion is complete
                while strcmp(motion, '+1') || strcmp(motion, '-1')
                    motion = writeread(app.LinearSlider, 'AXIS1:DIR?');
                    app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                    
                    sliderPosition = app.LinearSliderCurrentPosition.Value / 100; % Convert to meters
                    baseSpacing = app.LINEAR_SLIDER_RANGE - sliderPosition;
                    totalSpacing = baseSpacing + app.offsetSpacing;
                    
                    % Subtract antenna physical size if provided
                    if ~isempty(app.AntennaPhysicalSize.Value)
                        totalSpacing = totalSpacing - app.AntennaPhysicalSize.Value / 100; % Convert to meters
                    end
                    
                    % Update the displayed spacing
                    app.AntennaCurrentSpacing.Value = totalSpacing;
                    
                    % Optional: add a small delay to prevent overwhelming the system
                    pause(0.1);
                end
        
                app.setupSpacing = app.AntennaCurrentSpacing.Value;
                disp(['Final setup spacing: ', num2str(app.setupSpacing), ' meters']);
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopLinearSliderButton
        function LinearSliderStopMovement(app, event)
            % This function sends a stop command to halt the movement of the linear slider. It is triggered 
            % when the user presses the stop button in the linear slider settings menu. 

            try
                writeline(app.LinearSlider, 'AXIS1:STOP')
            catch ME
                app.displayError(ME);
            end
        end

        % Value changed function: AntennaPhysicalSize
        function LinearSliderApplyAntennaPhysicalOffset(app, event)
            AntennaPhysicalSizeOffset = app.AntennaPhysicalSize.Value;
    
            % Recalculate the current spacing
            sliderPosition = app.LinearSliderCurrentPosition.Value / 100; % Convert to meters
            baseSpacing = app.LINEAR_SLIDER_RANGE - sliderPosition;
            totalSpacing = baseSpacing + app.offsetSpacing;
            
            % Subtract antenna physical size offset
            if ~isempty(AntennaPhysicalSizeOffset)
                totalSpacing = totalSpacing - AntennaPhysicalSizeOffset / 100; % Convert to meters
            end
            
            % Update the displayed spacing
            app.AntennaCurrentSpacing.Value = totalSpacing;
            
            % Update the setup spacing
            app.setupSpacing = totalSpacing;
        end

        % Value changed function: TableSpeedSlider
        function TableChangeSpeed(app, event)
           % This function updates the turn-table speed value in the application when the user adjusts the TableSpeedSlider. While this 
           % function does not directly update the speed of the hardware, it ensures that the application's internal settings reflect the new speed. The updated value will be used in subsequent operations involving the turntable, allowing the user to adjust the speed without immediate hardware changes.
 
            try
                app.TableSpeedSlider.Value = app.TableSpeedSlider.Value; 
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: MovetoAngleButton
        function TableChangeAngle(app, event)
            % This function initiates the process of changing the turn-table angle to a new value. The function sends a command 
            % to set the turn-table's speed and a command to adjust the turn-table's angle to the user requested value. 
            % It continuously checks if the StopRequested flag is set to true and halts the operation if so.  
            
            try
                app.StopRequested = false;
                writeline(app.EMCenter, sprintf('1A:SPEED %d', app.TableSpeedSlider.Value));
                writeline(app.EMCenter, sprintf('1A:SK %d', app.TableNewAngle.Value));
                status = writeread(app.EMCenter,"1A:*OPC?");
                status = str2double(strtrim(status));

                while status ~= 1
                    drawnow;

                    if app.StopRequested
                        break;
                    end

                    status = writeread(app.EMCenter,"1A:*OPC?");
                    status = str2double(strtrim(status));
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopTableButton
        function TableStopMovement(app, event)
            % This function is executed when the user requests to stop the turn-table movement operation. It sets the 
            % StopRequested flag to true and sends a stop command to the table to halt any ongoing operation. 
            
            app.StopRequested = true;
            disp('User stopped turn-table movement.');
            
            try
                writeline(app.EMCenter, '1A:ST');
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: MoveTowertoAngleButton
        function TowerChangeAngle(app, event)
            % This function initiates the process of changing the tower's angle to a new value. The function sends a command 
            % to set the tower's speed to a default value of 20 and a command to adjust the tower's angle to the user requested value. 
            % It continuously checks if the StopRequested flag is set to true and halts the operation if so. 
            
            try
                app.StopRequested = false;
                writeline(app.EMCenter, '1B:SPEED 20');
                writeline(app.EMCenter, sprintf('1B:SK %d', app.TowerNewAngle.Value));
            
                while true                    
                    drawnow;
                    
                    if app.StopRequested
                        break;
                    end

                    pause(0.1);
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopTowerButton
        function TowerStopMovement(app, event)
            % This function is executed when the user requests to stop the tower movement operation. It sets the 
            % StopRequested flag to true and sends a stop command to the tower to halt any ongoing operation. 
            
            app.StopRequested = true;
            disp('User stopped tower movement.');

            try
                writeline(app.EMCenter, '1B:ST');
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StartTestButton
        function AntennaStartMeasurement(app, event)
            % This function validates the user inputs for the VNA measurement settings and performs the selected measurement 
            % type (either "Gain Characterization" or "Pattern Measurement"). It ensures that the start and end frequencies are set and distinct, 
            % the number of sweep points is specified, and the antenna physical size is provided. Based on the chosen measurement type, the 
            % function either calls the getBoresightGain method for gain characterization or the get2DAntennaGain method for 2D antenna pattern measurement. 
            
            try
                if isempty(app.VNAStartFrequency.Value) || isempty(app.VNAEndFrequency.Value) ||(app.VNAStartFrequency.Value == app.VNAEndFrequency.Value) 
                    error('Invalid frequency values, start and end frequencies must be set and different.');
                elseif isempty(app.VNASweepPoints.Value)
                    error('Number of sweep points not set.');
                elseif isempty(app.AntennaPhysicalSize.Value)
                    error('Antenna physical dimension offset not set');
                end
            
                startFrequency = app.VNAStartFrequency.Value * 1E6;
                endFrequency = app.VNAEndFrequency.Value * 1E6;
                sweepPoints = app.VNASweepPoints.Value;
            
                switch app.VNAMeasurement.Value
                    case 'Gain Characterization'
                        app.getBoresightGain(startFrequency, endFrequency, sweepPoints);
                    case 'Pattern Measurement'
                        app.get2DAntennaGain(startFrequency, endFrequency, sweepPoints);
                end
           
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopTestButton
        function AntennaStopMeasurement(app, event)
            % This function handles the user's request to stop the ongoing 2D radiation pattern measurement. When the stop request is triggered,
            % the function terminates the measurement process, checks if any data has been recorded, and prompts the user to decide whether to save 
            % the collected data. If the user opts to save, the function prepares the data and saves it. 

            app.StopRequested = true;
            disp('User stopped the test. Terminating measurement and saving data if necessary.');
            
            try
                writeline(app.EMCenter, '1A:ST');
                pause(1);

                % Check if any data has been recorded thus far.
                if ~isempty(app.AzimuthAngles) && any(app.AzimuthAngles)
                    % Identify valid data points since the arrays were
                    % initialized with 0's.
                    validIndices = app.TestFrequencies > 0;
                
                    % Filter data arrays
                    filteredAzimuthAngles = app.AzimuthAngles(validIndices);
                    filteredFrequencies = app.TestFrequencies(validIndices);
                    filteredAntennaGain = app.AntennaGain_dBi(validIndices);
                    filteredReturnLoss = app.S11(validIndices);
                
                    % Combine filtered data
                    combinedData = [double(filteredAzimuthAngles)', double(filteredFrequencies)', double(filteredAntennaGain)', double(filteredReturnLoss)'];
                
                    % Handle the case where no valid data exists
                    if isempty(combinedData)
                        disp('No valid data was recorded. Skipping save operation.');
                        return;
                    end
                
                    % Ask the user if they want to save the data
                    question = 'Do you want to save collected data?';
                    choice = uiconfirm(app.UIFigure, question, 'Stop Test', 'Options', {'Yes', 'No'}, 'DefaultOption', 1);
                
                    if strcmp(choice, 'Yes')
                        % Prepare data for the saveData function
                        combinedNames = {'Azimuth Angles (deg)', 'Frequency (Hz)', 'Gain (dBi)', 'Return Loss (dB)'};
                        passedLimit = length(app.AzimuthAngles) > app.EXCEL_MAX_ROWS;
                        saveData(combinedData, combinedNames, passedLimit);
                    end
                end
                
                % Reset antenna measurement properties for efficient data
                % handling.
                app.resetAntennaMeasurementProperties();
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: PlotResultsButton
        function AntennaPlotMeasurement(app, event)
            % This function is responsible for plotting the 2D antenna gain measurement results, providing 
            % the user with visual insights into the measurement data. 

            sweepPts = length(app.sweepFrequencies);
            app.plotAntenna2DMeasurement(sweepPts);
        end

        % Button pushed function: LoadResultsButton
        function AntennaLoadData(app, event)
            % This function is responsible for loading an antenna gain file into the application. 
            % The file should adhere to the application's predefined column headers for antenna gain data:
            %
            %       Azimuth Angles (deg)
            %       Frequency (Hz)
            %       Gain (dBi) (Gain in dBi)
            %       Return Loss (dB) 
            % 
            % If any fields are missing, an error is raised to inform the user. Upon successful loading, 
            % the data is extracted and used to populate relevant fields within the application, 
            % including dropdown menus for selecting specific angles and frequencies for plotting.

            try 
                % Load the antenna gain file
                combinedAntennaData = loadData('Antenna');

                % Check if the uploaded file is empty, or if the user
                % canceled the file selection.
                if isempty(fieldnames(combinedAntennaData))
                    return;
                end

                % Check each required field and add to the list if missing.
                missingFields = {};
                if ~isfield(combinedAntennaData, 'AzimuthAnglesdeg')
                    missingFields{end+1} = 'Azimuth Angles (deg)';
                end
                if ~isfield(combinedAntennaData, 'FrequencyHz')
                    missingFields{end+1} = 'Frequency (Hz)';
                end
                if ~isfield(combinedAntennaData, 'GaindBi')
                    missingFields{end+1} = 'Gain (dBi)';
                end
                if ~isfield(combinedAntennaData, 'ReturnLossdB')
                    missingFields{end+1} = 'Return Loss (dB)';
                end
            
                % If any fields are missing, raise an error telling the
                % user which field is missing. 
                if ~isempty(missingFields)
                    error(['The antenna gain file is missing the following required field(s): ', strjoin(missingFields, ', ')]);
                end

                % Extract data from the file and load it into the
                % application if all required fields are found.
                app.azimuthAngles = unique(combinedAntennaData.AzimuthAnglesdeg)';
                app.sweepFrequencies = unique(combinedAntennaData.FrequencyHz)';
                app.antennaGain = combinedAntennaData.GaindBi';
                app.antennaRL = combinedAntennaData.ReturnLossdB';

                % Populate dropdown menus, with the angles and frequencies
                % from the gain file so user can select which specific
                % angle or frequency to plot.
                app.PlotAngleDropDown.Items = string(app.azimuthAngles);
                app.PlotFrequencyGHzDropDown.Items = string(app.sweepFrequencies/1E9);

                % Set default values for the angle and frequency.
                app.selectedAngle = app.PlotAngleDropDown.Items(1);
                app.selectedFrequency = app.PlotFrequencyGHzDropDown.Items(1);

                % Set default values for the angle and frequency ranges, so
                % the plots only display the necessary data. 
                app.angleRange = 0;
                app.frequencyRange = 1;
            catch ME    
                app.displayError(ME);
            end
        end

        % Value changed function: PlotAngleDropDown
        function AntennaSelectedAngleValue(app, event)
            % This function automatically adjusts the angle range for plotting based on the user's selected angle from the dropdown menu.
            % It retrieves the selected angle and then calculates the corresponding index in the list of available angles.
            % The angle range is updated to reflect the index of the selected angle, which is used to determine which data to plot.

            app.selectedAngle = app.PlotAngleDropDown.Value;   
            app.angleRange = find(strcmp(app.PlotAngleDropDown.Items, app.selectedAngle)) - 1;
        end

        % Value changed function: PlotFrequencyGHzDropDown
        function AntennaSelectedFrequencyValue(app, event)
            % This function automatically adjusts the frequency range for plotting based on the user's selected frequency from the dropdown menu.
            % It retrieves the selected frequency and calculates the corresponding index in the list of available frequencies. 
            % The frequency range is updated to reflect the index of the selected frequency, which is used to determine which data to plot.

            app.selectedFrequency = app.PlotFrequencyGHzDropDown.Value;
            app.frequencyRange = find(strcmp(app.PlotFrequencyGHzDropDown.Items, app.selectedFrequency));
        end

        % Button pushed function: BrowseReferenceFileButton
        function LoadReferenceGainFile(app, event)
            % The reference gain file must adhere to the application's specified column headers format:
            %
            %       Frequency (Hz)
            %       Gain (dBi)
            %       Return Loss (dB) 
            % 
            % The function verifies that all required fields are present in the file, and if any are missing, it raises an error. 
            % If the file is valid, the path to the loaded reference gain file is displayed to the user.

            try 
                % Load the reference gain file
                app.ReferenceGainFile = loadData('Antenna');

                % Check if the uploaded file is empty, or if the user
                % canceled the file selection.
                if isempty(fieldnames(app.ReferenceGainFile))
                    return;
                end
            
                % Check each required field and add to the list if missing.
                missingFields = {};
                if ~isfield(app.ReferenceGainFile, 'FrequencyHz')
                    missingFields{end+1} = 'Frequency (Hz)';
                end
                if ~isfield(app.ReferenceGainFile, 'GaindBi')
                    missingFields{end+1} = 'Gain (dBi)';
                end
                if ~isfield(app.ReferenceGainFile, 'ReturnLossdB')
                    missingFields{end+1} = 'Return Loss (dB)';
                end
            
                % If any fields are missing, raise an error telling the
                % user which field is missing. 
                if ~isempty(missingFields)
                    error(['The reference gain file is missing the following required field(s): ', strjoin(missingFields, ', ')]);
                end

                % If the file presents no errors, the file path is loaded
                % into the file, and displayed to the user.
                app.ReferenceGainFileField.Value = evalin('base', 'loadedFilePath');
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: PlotReferenceGainButton
        function PlotReferenceGainFile(app, event)
            % This function plots the reference gain data that has been loaded into the application. It extracts the frequency, 
            % antenna gain, and return loss from the reference gain file and displays them on the corresponding plots for the user.

            try
                % Clear current plots.
                cla(app.GainvsFrequencyBoresightGain);
                cla(app.ReturnLossBoresightGain);

                % Extract antenna measurement parameters from the file.
                Frequencies = app.ReferenceGainFile.FrequencyHz / 1E9;
                AntennaGain =  app.ReferenceGainFile.GaindBi;
                ReturnLoss =  app.ReferenceGainFile.ReturnLossdB;

                % Plot the parameters onto the plots.
                plot(app.GainvsFrequencyBoresightGain, Frequencies, AntennaGain);
                plot(app.ReturnLossBoresightGain, Frequencies, ReturnLoss);

                % Improves the plot appeareance, line thickness can be
                % modified.
                improveAxesAppearance(app.GainvsFrequencyBoresightGain, 'LineThickness', 2);
                improveAxesAppearance(app.ReturnLossBoresightGain, 'LineThickness', 2);
            catch ME
                app.displayError(ME);
            end
        end

        % Close request function: UIFigure
        function closeupFcn(app, event)
            % This function handles the app's closure behavior. When the user attempts to close the app, the function prompts them 
            % with a confirmation dialog. If the user confirms the closure, the function will clean up by closing all active instrument 
            % connections. If the user cancels the closure, the function does nothing and returns to the app.

            InstrumentConnections = {app.PowerSupplyA, app.PowerSupplyB, app.SignalGenerator, app.SpectrumAnalyzer, app.VNA, app.EMCenter, app.LinearSlider};
            question = 'Close ARES App?';
            choice = uiconfirm(app.UIFigure, question, 'Confirm Close', 'Options', {'Yes', 'No'});

            switch choice
                case 'Yes'
                    for i = 1:numel(InstrumentConnections)
                        if ~isempty(InstrumentConnections{i})
                            delete(InstrumentConnections{i});
                        end
                    end
                    app.delete
                case 'No'
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeupFcn, true);
            app.GridLayout19 = uigridlayout(app.UIFigure);
            app.MainTabs = uitabgroup(app.GridLayout19);
            app.InfoTab = uitab(app.MainTabs);
            app.InfoTab.Title = 'Info';
            app.GridLayout20 = uigridlayout(app.InfoTab);
            app.Image = uiimage(app.GridLayout20);
            app.Image.ImageSource = 'ARES logo.jpg';
            app.Label = uilabel(app.GridLayout20);
            app.Label.Text = 'Automated Radio Evaluation Suite';
            app.Label_2 = uilabel(app.GridLayout20);
            app.Label_2.Text = '';
            app.V02BETARelaseLabel = uilabel(app.GridLayout20);
            app.V02BETARelaseLabel.Text = 'V0.2 BETA Relase';
            app.Hyperlink = uihyperlink(app.GridLayout20);
            app.Hyperlink.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite';
            app.Hyperlink.Text = 'GitHub Repo';
            app.AuthorJoseAbrahanBolaosVargasMentorAlexDSantiagoVargasLabel = uilabel(app.GridLayout20);
            app.AuthorJoseAbrahanBolaosVargasMentorAlexDSantiagoVargasLabel.Text = {'Author: Jose Abrahan Bolaños Vargas'; 'Mentor: Alex D. Santiago Vargas'; 'PI: Dimitrios Peroulis'; 'Adaptive Radio Electronics and Sensors (ARES) Group'};
            app.Hyperlink2 = uihyperlink(app.GridLayout20);
            app.Hyperlink2.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite?tab=readme-ov-file#pa-measurement-tutorial';
            app.Hyperlink2.Text = 'PA Measurement Tutorial';
            app.Hyperlink2_2 = uihyperlink(app.GridLayout20);
            app.Hyperlink2_2.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite?tab=readme-ov-file#antenna-measurement-tutorial';
            app.Hyperlink2_2.Text = 'Antenna Tutorial';
            app.Hyperlink2_3 = uihyperlink(app.GridLayout20);
            app.Hyperlink2_3.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite/blob/main/README.md';
            app.Hyperlink2_3.Text = 'README';
            app.PowerAmplifierTab = uitab(app.MainTabs);
            app.PowerAmplifierTab.Title = 'Power Amplifier ';
            app.GridLayout28 = uigridlayout(app.PowerAmplifierTab);
            app.ResultsTabs = uitabgroup(app.GridLayout28);
            app.SingleTab = uitab(app.ResultsTabs);
            app.SingleTab.Title = 'Single';
            app.GridLayout16 = uigridlayout(app.SingleTab);
            app.SingleGain = uiaxes(app.GridLayout16);
            title(app.SingleGain, 'Gain, DE, PAE vs. Output Power')
            xlabel(app.SingleGain, 'Output Power (dBm)')
            zlabel(app.SingleGain, 'Z')
            app.SweepTab = uitab(app.ResultsTabs);
            app.SweepTab.Title = 'Sweep';
            app.GridLayout2 = uigridlayout(app.SweepTab);
            app.GainvsOutputPowerPlot = uiaxes(app.GridLayout2);
            title(app.GainvsOutputPowerPlot, 'Gain vs. Output Power')
            xlabel(app.GainvsOutputPowerPlot, 'Output Power (dBm)')
            ylabel(app.GainvsOutputPowerPlot, 'Gain (dB)')
            zlabel(app.GainvsOutputPowerPlot, 'Z')
            app.PeakGainPlot = uiaxes(app.GridLayout2);
            title(app.PeakGainPlot, 'Peak Gain')
            xlabel(app.PeakGainPlot, 'Frequency (GHz)')
            ylabel(app.PeakGainPlot, 'Gain (dB)')
            zlabel(app.PeakGainPlot, 'Z')
            app.PeakDEPAEPlot = uiaxes(app.GridLayout2);
            title(app.PeakDEPAEPlot, 'Peak DE and PAE')
            xlabel(app.PeakDEPAEPlot, 'Frequency (GHz)')
            ylabel(app.PeakDEPAEPlot, 'Efficiency (%)')
            zlabel(app.PeakDEPAEPlot, 'Z')
            app.CompressionPointsPlot = uiaxes(app.GridLayout2);
            title(app.CompressionPointsPlot, 'Saturation Power and Compression Points')
            xlabel(app.CompressionPointsPlot, 'Frequency (GHz)')
            ylabel(app.CompressionPointsPlot, 'Output Power (dBm)')
            zlabel(app.CompressionPointsPlot, 'Z')
            app.PASettingsPanel = uitabgroup(app.GridLayout28);
            app.InstrumentsTab = uitab(app.PASettingsPanel);
            app.InstrumentsTab.Title = 'Instruments';
            app.GridLayout27 = uigridlayout(app.InstrumentsTab);
            app.PAScanButton = uibutton(app.GridLayout27, 'push');
            app.PAScanButton.ButtonPushedFcn = createCallbackFcn(app, @PAScanResources, true);
            app.PAScanButton.Text = 'Scan';
            app.PAConnectButton = uibutton(app.GridLayout27, 'push');
            app.PAConnectButton.ButtonPushedFcn = createCallbackFcn(app, @PAConnectInstruments, true);
            app.PAConnectButton.Text = 'Connect';
            app.PowerSupplyAStatusLabel = uilabel(app.GridLayout27);
            app.PowerSupplyAStatusLabel.Text = 'Status:';
            app.PowerSupplyBStatusLabel = uilabel(app.GridLayout27);
            app.PowerSupplyBStatusLabel.Text = 'Status:';
            app.SignalGeneratorStatusLabel = uilabel(app.GridLayout27);
            app.SignalGeneratorStatusLabel.Text = 'Status:';
            app.SpectrumAnalyzerStatusLabel = uilabel(app.GridLayout27);
            app.SpectrumAnalyzerStatusLabel.Text = 'Status:';
            app.PowerSupplyALabel = uilabel(app.GridLayout27);
            app.PowerSupplyALabel.Text = 'Power Supply A';
            app.PowerSupplyADropDown = uidropdown(app.GridLayout27);
            app.PowerSupplyADropDown.Items = {'Address'};
            app.PowerSupplyADropDown.Value = 'Address';
            app.PowerSupplyBDropDown = uidropdown(app.GridLayout27);
            app.PowerSupplyBDropDown.Items = {'Address'};
            app.PowerSupplyBDropDown.Value = 'Address';
            app.PowerSupplyBLabel = uilabel(app.GridLayout27);
            app.PowerSupplyBLabel.Text = 'Power Supply B';
            app.SignalGeneratorDropDown = uidropdown(app.GridLayout27);
            app.SignalGeneratorDropDown.Items = {'Address'};
            app.SignalGeneratorDropDown.Value = 'Address';
            app.SignalGeneratorLabel = uilabel(app.GridLayout27);
            app.SignalGeneratorLabel.Text = 'Signal Generator';
            app.SpectrumAnalyzerDropDown = uidropdown(app.GridLayout27);
            app.SpectrumAnalyzerDropDown.Items = {'Address'};
            app.SpectrumAnalyzerDropDown.Value = 'Address';
            app.SpectrumAnalyzerLabel = uilabel(app.GridLayout27);
            app.SpectrumAnalyzerLabel.Text = 'Spectrum Analyzer';
            app.MeasurementTypeDropDownLabel = uilabel(app.GridLayout27);
            app.MeasurementTypeDropDownLabel.Text = 'Measurement Type';
            app.MeasurementTypeDropDown = uidropdown(app.GridLayout27);
            app.MeasurementTypeDropDown.Items = {'Single Frequency', 'Sweep Frequencies'};
            app.MeasurementTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PAMeasurementType, true);
            app.MeasurementTypeDropDown.Value = 'Single Frequency';
            app.MeasurementDelaysEditFieldLabel = uilabel(app.GridLayout27);
            app.MeasurementDelaysEditFieldLabel.Text = 'Measurement Delay (s)';
            app.MeasurementDelayValueField = uieditfield(app.GridLayout27, 'numeric');
            app.MeasurementDelayValueField.Limits = [0 Inf];
            app.MeasurementDelayValueField.Value = 0.1;
            app.PowerSupplyTab = uitab(app.PASettingsPanel);
            app.PowerSupplyTab.Title = 'Power Supply';
            app.GridLayout29 = uigridlayout(app.PowerSupplyTab);
            app.PowerSupplyModeDropDown = uidropdown(app.GridLayout29);
            app.PowerSupplyModeDropDown.Items = {'No Supply', 'Single Supply', 'Dual Supply', 'Quad Supply'};
            app.PowerSupplyModeDropDown.ValueChangedFcn = createCallbackFcn(app, @PSUChangeMode, true);
            app.PowerSupplyModeDropDown.Value = 'Single Supply';
            app.PowerSupplyChannelDropDown = uidropdown(app.GridLayout29);
            app.PowerSupplyChannelDropDown.Items = {''};
            app.PowerSupplyChannelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PSUChannelDropDownOpening, true);
            app.PowerSupplyChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @PSUChangeChannel, true);
            app.PowerSupplyChannelDropDown.Value = '';
            app.DrainGateSwitch = uiswitch(app.GridLayout29, 'slider');
            app.DrainGateSwitch.Items = {'Drain', 'Gate'};
            app.DrainGateSwitch.ValueChangedFcn = createCallbackFcn(app, @DrainGateSwitchValueChanged, true);
            app.DrainGateSwitch.Value = 'Drain';
            app.SingleSweepSwitch = uiswitch(app.GridLayout29, 'slider');
            app.SingleSweepSwitch.Items = {'Single', 'Sweep'};
            app.SingleSweepSwitch.ValueChangedFcn = createCallbackFcn(app, @SingleSweepSwitchValueChanged, true);
            app.SingleSweepSwitch.Value = 'Single';
            app.SupplyCurrentValueField = uieditfield(app.GridLayout29, 'numeric');
            app.SupplyCurrentValueField.ValueChangedFcn = createCallbackFcn(app, @PSUSetLimitingCurrent, true);
            app.LimitingCurrentALabel = uilabel(app.GridLayout29);
            app.LimitingCurrentALabel.Text = 'Limiting Current (A)';
            app.SupplyVoltageStartNameField = uilabel(app.GridLayout29);
            app.SupplyVoltageStartNameField.Text = 'Start Voltage (V)';
            app.SupplyVoltageStartValueField = uieditfield(app.GridLayout29, 'numeric');
            app.SupplyVoltageStartValueField.ValueChangedFcn = createCallbackFcn(app, @PSUSetStartVoltage, true);
            app.SupplyVoltageStopEditField = uilabel(app.GridLayout29);
            app.SupplyVoltageStopEditField.Text = 'Stop Voltage (V)';
            app.SupplyVoltageStopValueField = uieditfield(app.GridLayout29, 'numeric');
            app.SupplyVoltageStopValueField.ValueChangedFcn = createCallbackFcn(app, @PSUSetStopVoltage, true);
            app.SupplyVoltageStepNameField = uilabel(app.GridLayout29);
            app.SupplyVoltageStepNameField.Text = 'Step Voltage (V)';
            app.SupplyVoltageStepValueField = uieditfield(app.GridLayout29, 'numeric');
            app.SupplyVoltageStepValueField.ValueChangedFcn = createCallbackFcn(app, @PSUSetStepVoltage, true);
            app.ModeDropDownLabel = uilabel(app.GridLayout29);
            app.ModeDropDownLabel.Text = 'Mode';
            app.SupplyChannelDropDownLabel = uilabel(app.GridLayout29);
            app.SupplyChannelDropDownLabel.Text = 'Supply Channel';
            app.SignalGeneratorTab = uitab(app.PASettingsPanel);
            app.SignalGeneratorTab.Title = 'Signal Generator';
            app.GridLayout24 = uigridlayout(app.SignalGeneratorTab);
            app.EndFrequencyMHzLabel = uilabel(app.GridLayout24);
            app.EndFrequencyMHzLabel.Text = 'End Frequency (MHz)';
            app.StartFrequencyMHzLabel = uilabel(app.GridLayout24);
            app.StartFrequencyMHzLabel.Text = 'Start Frequency (MHz)';
            app.FrequencyStepMHzLabel = uilabel(app.GridLayout24);
            app.FrequencyStepMHzLabel.Text = 'Frequency Step (MHz)';
            app.StartPowerdBmEditFieldLabel = uilabel(app.GridLayout24);
            app.StartPowerdBmEditFieldLabel.Text = 'Start Power (dBm)';
            app.EndPowerdBmEditFieldLabel = uilabel(app.GridLayout24);
            app.EndPowerdBmEditFieldLabel.Text = 'End Power (dBm)';
            app.PowerStepdBmEditField = uilabel(app.GridLayout24);
            app.PowerStepdBmEditField.Text = 'Power Step (dBm)';
            app.StartFrequency = uieditfield(app.GridLayout24, 'numeric');
            app.EndFrequency = uieditfield(app.GridLayout24, 'numeric');
            app.FrequencyStep = uieditfield(app.GridLayout24, 'numeric');
            app.StartPower = uieditfield(app.GridLayout24, 'numeric');
            app.EndPower = uieditfield(app.GridLayout24, 'numeric');
            app.PowerStep = uieditfield(app.GridLayout24, 'numeric');
            app.SpectrumAnalyzerTab = uitab(app.PASettingsPanel);
            app.SpectrumAnalyzerTab.Title = 'Spectrum Analyzer';
            app.GridLayout25 = uigridlayout(app.SpectrumAnalyzerTab);
            app.CenterFrequencyNameField = uilabel(app.GridLayout25);
            app.CenterFrequencyNameField.Text = 'Center Frequency (MHz)';
            app.CenterFrequencyValueField = uieditfield(app.GridLayout25, 'numeric');
            app.SpanNameField = uilabel(app.GridLayout25);
            app.SpanNameField.Text = 'Span (MHz)';
            app.SpanValueField = uieditfield(app.GridLayout25, 'numeric');
            app.SweepPointsNameField = uilabel(app.GridLayout25);
            app.SweepPointsNameField.Text = 'Sweep Points';
            app.SweepPointsValueField = uieditfield(app.GridLayout25, 'numeric');
            app.ReferenceLevelNameField = uilabel(app.GridLayout25);
            app.ReferenceLevelNameField.Text = 'Reference Level (dB)';
            app.ReferenceLevelValueField = uieditfield(app.GridLayout25, 'numeric');
            app.AttenuationdBEditFieldLabel = uilabel(app.GridLayout25);
            app.AttenuationdBEditFieldLabel.Text = 'Attenuation (dB)';
            app.AttenuationValueField = uieditfield(app.GridLayout25, 'numeric');
            app.CalibrationTab = uitab(app.PASettingsPanel);
            app.CalibrationTab.Title = 'Calibration';
            app.MeasurementSettingsPanel = uipanel(app.GridLayout28);
            app.MeasurementSettingsPanel.Title = 'Measurement Settings';
            app.GridLayout12 = uigridlayout(app.MeasurementSettingsPanel);
            app.StartPAMeasurement = uibutton(app.GridLayout12, 'push');
            app.StartPAMeasurement.ButtonPushedFcn = createCallbackFcn(app, @PAStartMeasurement, true);
            app.StartPAMeasurement.Text = 'Start Test';
            app.LoadPAData = uibutton(app.GridLayout12, 'push');
            app.LoadPAData.ButtonPushedFcn = createCallbackFcn(app, @PALoadData, true);
            app.LoadPAData.Text = 'Load Test';
            app.SavePAData = uibutton(app.GridLayout12, 'push');
            app.SavePAData.ButtonPushedFcn = createCallbackFcn(app, @PASaveData, true);
            app.SavePAData.Text = 'Save Test';
            app.PlotPAData = uibutton(app.GridLayout12, 'push');
            app.PlotPAData.ButtonPushedFcn = createCallbackFcn(app, @PAPlotMeasurement, true);
            app.PlotPAData.Text = 'Plot Results';
            app.AntennaTab = uitab(app.MainTabs);
            app.AntennaTab.Title = 'Antenna ';
            app.GridLayout17 = uigridlayout(app.AntennaTab);
            app.AntennaPattern = uitabgroup(app.GridLayout17);
            app.MeasurementAntennaGainTab = uitab(app.AntennaPattern);
            app.MeasurementAntennaGainTab.Title = 'Measurement Antenna Gain';
            app.GridLayout3_2 = uigridlayout(app.MeasurementAntennaGainTab);
            app.ReturnLossBoresightGain = uiaxes(app.GridLayout3_2);
            title(app.ReturnLossBoresightGain, 'Return Loss vs. Frequency')
            xlabel(app.ReturnLossBoresightGain, 'Frequency (GHz)')
            ylabel(app.ReturnLossBoresightGain, 'RL (dB)')
            zlabel(app.ReturnLossBoresightGain, 'Z')
            app.GainvsFrequencyBoresightGain = uiaxes(app.GridLayout3_2);
            title(app.GainvsFrequencyBoresightGain, 'Gain vs. Frequency')
            xlabel(app.GainvsFrequencyBoresightGain, 'Frequency (GHz)')
            ylabel(app.GainvsFrequencyBoresightGain, 'Gain (dBi)')
            zlabel(app.GainvsFrequencyBoresightGain, 'Z')
            app.PlotReferenceGainButton = uibutton(app.GridLayout3_2, 'push');
            app.PlotReferenceGainButton.ButtonPushedFcn = createCallbackFcn(app, @PlotReferenceGainFile, true);
            app.PlotReferenceGainButton.Text = 'Plot Reference Gain';
            app.ReferenceGainFileField = uieditfield(app.GridLayout3_2, 'text');
            app.ReferenceGainFileField.Editable = 'off';
            app.BrowseReferenceFileButton = uibutton(app.GridLayout3_2, 'push');
            app.BrowseReferenceFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceGainFile, true);
            app.BrowseReferenceFileButton.Text = 'Browse Reference File';
            app.DPatternMeasurementTab = uitab(app.AntennaPattern);
            app.DPatternMeasurementTab.Title = '2D Pattern Measurement';
            app.GridLayout3 = uigridlayout(app.DPatternMeasurementTab);
            app.ReturnLoss2D = uiaxes(app.GridLayout3);
            title(app.ReturnLoss2D, 'Return Loss')
            xlabel(app.ReturnLoss2D, 'Frequency (GHz)')
            ylabel(app.ReturnLoss2D, 'RL (dB)')
            zlabel(app.ReturnLoss2D, 'Z')
            app.GainvsFrequency2D = uiaxes(app.GridLayout3);
            title(app.GainvsFrequency2D, 'Gain vs. Frequency')
            xlabel(app.GainvsFrequency2D, 'Frequency (GHz)')
            ylabel(app.GainvsFrequency2D, 'Gain (dBi)')
            zlabel(app.GainvsFrequency2D, 'Z')
            app.GainvsAngle2D = uiaxes(app.GridLayout3);
            title(app.GainvsAngle2D, 'Gain vs. Angle')
            xlabel(app.GainvsAngle2D, 'Angle (°)')
            ylabel(app.GainvsAngle2D, 'Gain (dBi)')
            zlabel(app.GainvsAngle2D, 'Z')
            app.AntennaSettings = uitabgroup(app.GridLayout17);
            app.VNATab = uitab(app.AntennaSettings);
            app.VNATab.Title = 'VNA';
            app.GridLayout21 = uigridlayout(app.VNATab);
            app.StartFrequectSpanMHzLabel_15 = uilabel(app.GridLayout21);
            app.StartFrequectSpanMHzLabel_15.Text = 'Start Frequency';
            app.EndFrequencyMHzLabel_6 = uilabel(app.GridLayout21);
            app.EndFrequencyMHzLabel_6.Text = 'End Frequency';
            app.VNAMeasurement = uidropdown(app.GridLayout21);
            app.VNAMeasurement.Items = {'Gain Characterization', 'Pattern Measurement'};
            app.VNAMeasurement.Value = 'Gain Characterization';
            app.VNASweepPoints = uieditfield(app.GridLayout21, 'numeric');
            app.VNASweepPoints.Limits = [0 Inf];
            app.VNASweepPoints.AllowEmpty = 'on';
            app.VNASweepPoints.Value = [];
            app.VNAStartFrequency = uieditfield(app.GridLayout21, 'numeric');
            app.VNAStartFrequency.Limits = [0 Inf];
            app.VNAStartFrequency.ValueDisplayFormat = '%11.4g (MHz)';
            app.VNAStartFrequency.AllowEmpty = 'on';
            app.VNAStartFrequency.Value = [];
            app.VNAEndFrequency = uieditfield(app.GridLayout21, 'numeric');
            app.VNAEndFrequency.Limits = [0 Inf];
            app.VNAEndFrequency.ValueDisplayFormat = '%11.4g (MHz)';
            app.VNAEndFrequency.AllowEmpty = 'on';
            app.VNAEndFrequency.Value = [];
            app.MeasurementDropDown_2Label = uilabel(app.GridLayout21);
            app.MeasurementDropDown_2Label.Text = 'Measurement';
            app.SweepPointsLabel = uilabel(app.GridLayout21);
            app.SweepPointsLabel.Text = 'Sweep Points';
            app.Table = uitab(app.AntennaSettings);
            app.Table.Title = 'Table';
            app.GridLayout8 = uigridlayout(app.Table);
            app.StartFrequectSpanMHzLabel_11 = uilabel(app.GridLayout8);
            app.StartFrequectSpanMHzLabel_11.Text = 'Start Angle ';
            app.TableStartAngle = uieditfield(app.GridLayout8, 'numeric');
            app.TableStartAngle.Limits = [-180 180];
            app.TableStartAngle.AllowEmpty = 'on';
            app.TableStartAngle.Placeholder = '(deg)';
            app.TableStartAngle.Value = [];
            app.StartFrequectSpanMHzLabel_12 = uilabel(app.GridLayout8);
            app.StartFrequectSpanMHzLabel_12.Text = 'Angle Step Size ';
            app.TableStepAngle = uieditfield(app.GridLayout8, 'numeric');
            app.TableStepAngle.AllowEmpty = 'on';
            app.TableStepAngle.Placeholder = '(deg)';
            app.TableStepAngle.Value = [];
            app.StartFrequectSpanMHzLabel_13 = uilabel(app.GridLayout8);
            app.StartFrequectSpanMHzLabel_13.Text = 'End Angle ';
            app.TableEndAngle = uieditfield(app.GridLayout8, 'numeric');
            app.TableEndAngle.Limits = [-180 180];
            app.TableEndAngle.AllowEmpty = 'on';
            app.TableEndAngle.Placeholder = '(deg)';
            app.TableEndAngle.Value = [];
            app.StopTableButton = uibutton(app.GridLayout8, 'push');
            app.StopTableButton.ButtonPushedFcn = createCallbackFcn(app, @TableStopMovement, true);
            app.StopTableButton.Text = 'Stop Table';
            app.TableNewAngle = uieditfield(app.GridLayout8, 'numeric');
            app.TableNewAngle.ValueDisplayFormat = '%11.4g (deg)';
            app.TableSpeedSliderLabel = uilabel(app.GridLayout8);
            app.TableSpeedSliderLabel.Text = 'Table Speed';
            app.TableSpeedSlider = uislider(app.GridLayout8);
            app.TableSpeedSlider.ValueChangedFcn = createCallbackFcn(app, @TableChangeSpeed, true);
            app.TableSpeedSlider.Value = 50;
            app.MovetoAngleButton = uibutton(app.GridLayout8, 'push');
            app.MovetoAngleButton.ButtonPushedFcn = createCallbackFcn(app, @TableChangeAngle, true);
            app.MovetoAngleButton.Text = 'Move to Angle';
            app.TowerTab = uitab(app.AntennaSettings);
            app.TowerTab.Title = 'Tower';
            app.GridLayout9 = uigridlayout(app.TowerTab);
            app.StartFrequectSpanMHzLabel_16 = uilabel(app.GridLayout9);
            app.StartFrequectSpanMHzLabel_16.Enable = 'off';
            app.StartFrequectSpanMHzLabel_16.Text = 'Start Angle ';
            app.TowerStartAngle = uieditfield(app.GridLayout9, 'numeric');
            app.TowerStartAngle.Limits = [0 360];
            app.TowerStartAngle.AllowEmpty = 'on';
            app.TowerStartAngle.Enable = 'off';
            app.TowerStartAngle.Placeholder = '(deg)';
            app.TowerStartAngle.Value = [];
            app.StartFrequectSpanMHzLabel_17 = uilabel(app.GridLayout9);
            app.StartFrequectSpanMHzLabel_17.Enable = 'off';
            app.StartFrequectSpanMHzLabel_17.Text = 'Angle Step Size ';
            app.TowerStepAngle = uieditfield(app.GridLayout9, 'numeric');
            app.TowerStepAngle.AllowEmpty = 'on';
            app.TowerStepAngle.Enable = 'off';
            app.TowerStepAngle.Placeholder = '(deg)';
            app.TowerStepAngle.Value = [];
            app.StartFrequectSpanMHzLabel_18 = uilabel(app.GridLayout9);
            app.StartFrequectSpanMHzLabel_18.Enable = 'off';
            app.StartFrequectSpanMHzLabel_18.Text = 'End Angle ';
            app.TowerEndAngle = uieditfield(app.GridLayout9, 'numeric');
            app.TowerEndAngle.Limits = [0 360];
            app.TowerEndAngle.AllowEmpty = 'on';
            app.TowerEndAngle.Enable = 'off';
            app.TowerEndAngle.Placeholder = '(deg)';
            app.TowerEndAngle.Value = [];
            app.StopTowerButton = uibutton(app.GridLayout9, 'push');
            app.StopTowerButton.ButtonPushedFcn = createCallbackFcn(app, @TowerStopMovement, true);
            app.StopTowerButton.Text = 'Stop Tower';
            app.TowerSpeedSliderLabel = uilabel(app.GridLayout9);
            app.TowerSpeedSliderLabel.Text = 'Tower Speed';
            app.TowerSpeedSlider = uislider(app.GridLayout9);
            app.TowerSpeedSlider.Value = 50;
            app.TowerNewAngle = uieditfield(app.GridLayout9, 'numeric');
            app.TowerNewAngle.Limits = [-180 180];
            app.TowerNewAngle.ValueDisplayFormat = '%11.4g (deg)';
            app.MoveTowertoAngleButton = uibutton(app.GridLayout9, 'push');
            app.MoveTowertoAngleButton.ButtonPushedFcn = createCallbackFcn(app, @TowerChangeAngle, true);
            app.MoveTowertoAngleButton.Text = 'Move to Angle';
            app.LinearSliderTab = uitab(app.AntennaSettings);
            app.LinearSliderTab.Title = 'Linear Slider';
            app.GridLayout10 = uigridlayout(app.LinearSliderTab);
            app.StopLinearSliderButton = uibutton(app.GridLayout10, 'push');
            app.StopLinearSliderButton.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderStopMovement, true);
            app.StopLinearSliderButton.Text = 'Stop Linear Slider';
            app.SpeedPresetDropDownLabel = uilabel(app.GridLayout10);
            app.SpeedPresetDropDownLabel.Text = 'Speed Preset';
            app.SpeedPresetDropDown = uidropdown(app.GridLayout10);
            app.SpeedPresetDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.SpeedPresetDropDown.ValueChangedFcn = createCallbackFcn(app, @LinearSliderChangeSpeed, true);
            app.SpeedPresetDropDown.Value = '1';
            app.CurrentSpacingLabel = uilabel(app.GridLayout10);
            app.CurrentSpacingLabel.Text = 'Current Spacing ';
            app.AntennaCurrentSpacing = uieditfield(app.GridLayout10, 'numeric');
            app.AntennaCurrentSpacing.ValueDisplayFormat = '%11.4g (m)';
            app.AntennaCurrentSpacing.AllowEmpty = 'on';
            app.AntennaCurrentSpacing.Editable = 'off';
            app.AntennaCurrentSpacing.Value = [];
            app.SliderPositioncmLabel = uilabel(app.GridLayout10);
            app.SliderPositioncmLabel.Text = 'Slider Current Position';
            app.LinearSliderCurrentPosition = uieditfield(app.GridLayout10, 'numeric');
            app.LinearSliderCurrentPosition.ValueDisplayFormat = '%11.4g (cm)';
            app.LinearSliderCurrentPosition.AllowEmpty = 'on';
            app.LinearSliderCurrentPosition.Editable = 'off';
            app.LinearSliderCurrentPosition.Value = [];
            app.MovetoPositionButton = uibutton(app.GridLayout10, 'push');
            app.MovetoPositionButton.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderChangePosition, true);
            app.MovetoPositionButton.Text = 'Move to Position';
            app.LinearSliderNewPosition = uieditfield(app.GridLayout10, 'numeric');
            app.LinearSliderNewPosition.Limits = [0 200];
            app.LinearSliderNewPosition.Placeholder = 'cm';
            app.ConnectButton = uibutton(app.GridLayout10, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderConnect, true);
            app.ConnectButton.Text = 'Connect';
            app.AntennaOffsetLabel = uilabel(app.GridLayout10);
            app.AntennaOffsetLabel.Text = 'Antenna Offset ';
            app.AntennaPhysicalSize = uieditfield(app.GridLayout10, 'numeric');
            app.AntennaPhysicalSize.Limits = [0 Inf];
            app.AntennaPhysicalSize.ValueDisplayFormat = '%11.4g (cm)';
            app.AntennaPhysicalSize.AllowEmpty = 'on';
            app.AntennaPhysicalSize.ValueChangedFcn = createCallbackFcn(app, @LinearSliderApplyAntennaPhysicalOffset, true);
            app.AntennaPhysicalSize.Value = [];
            app.InstrumentConnectionsPanel_2 = uipanel(app.GridLayout17);
            app.InstrumentConnectionsPanel_2.Title = 'Instrument Connections';
            app.GridLayout6 = uigridlayout(app.InstrumentConnectionsPanel_2);
            app.AntennaScanButton = uibutton(app.GridLayout6, 'push');
            app.AntennaScanButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaScanResources, true);
            app.AntennaScanButton.Text = 'Scan';
            app.AntennaConnectButton = uibutton(app.GridLayout6, 'push');
            app.AntennaConnectButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaConnectInstruments, true);
            app.AntennaConnectButton.Text = 'Connect';
            app.VNADropDownLabel = uilabel(app.GridLayout6);
            app.VNADropDownLabel.Text = 'VNA';
            app.VNADropDown = uidropdown(app.GridLayout6);
            app.VNADropDown.Items = {'Address'};
            app.VNADropDown.Value = 'Address';
            app.EMCenterDropDownLabel = uilabel(app.GridLayout6);
            app.EMCenterDropDownLabel.Text = 'EM Center';
            app.EMCenterDropDown = uidropdown(app.GridLayout6);
            app.EMCenterDropDown.Items = {'Address'};
            app.EMCenterDropDown.Value = 'Address';
            app.TestandPlotSettingsPanel = uipanel(app.GridLayout17);
            app.TestandPlotSettingsPanel.Title = 'Test and Plot Settings';
            app.GridLayout4 = uigridlayout(app.TestandPlotSettingsPanel);
            app.PlotFrequencyGHzDropDownLabel = uilabel(app.GridLayout4);
            app.PlotFrequencyGHzDropDownLabel.Text = 'Plot Frequency (GHz)';
            app.PlotFrequencyGHzDropDown = uidropdown(app.GridLayout4);
            app.PlotFrequencyGHzDropDown.Items = {''};
            app.PlotFrequencyGHzDropDown.ValueChangedFcn = createCallbackFcn(app, @AntennaSelectedFrequencyValue, true);
            app.PlotFrequencyGHzDropDown.Value = '';
            app.PlottingAngleLabel = uilabel(app.GridLayout4);
            app.PlottingAngleLabel.Text = 'Plot Angle (°)';
            app.PlotAngleDropDown = uidropdown(app.GridLayout4);
            app.PlotAngleDropDown.Items = {''};
            app.PlotAngleDropDown.ValueChangedFcn = createCallbackFcn(app, @AntennaSelectedAngleValue, true);
            app.PlotAngleDropDown.Value = '';
            app.StartTestButton = uibutton(app.GridLayout4, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaStartMeasurement, true);
            app.StartTestButton.Text = 'Start Test';
            app.PlotResultsButton = uibutton(app.GridLayout4, 'push');
            app.PlotResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaPlotMeasurement, true);
            app.PlotResultsButton.Text = 'Plot Results';
            app.LoadResultsButton = uibutton(app.GridLayout4, 'push');
            app.LoadResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaLoadData, true);
            app.LoadResultsButton.Text = 'Load Results';
            app.StopTestButton = uibutton(app.GridLayout4, 'push');
            app.StopTestButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaStopMeasurement, true);
            app.StopTestButton.Text = 'Stop Test';
            app.Tab = uitab(app.MainTabs);
            app.Tab.Title = 'Tab';
            app.InstrumentConnectionsPanel_3 = uipanel(app.Tab);
            app.InstrumentConnectionsPanel_3.Title = 'Instrument Connections';
            app.PAScanButton_2 = uibutton(app.InstrumentConnectionsPanel_3, 'push');
            app.PAScanButton_2.ButtonPushedFcn = createCallbackFcn(app, @PAScanResources, true);
            app.PAScanButton_2.Text = 'Scan';
            app.PAConnectButton_2 = uibutton(app.InstrumentConnectionsPanel_3, 'push');
            app.PAConnectButton_2.Text = 'Connect';
            app.PowerSupplyDropDown_2Label = uilabel(app.Tab);
            app.PowerSupplyDropDown_2Label.Text = 'Power Supply';
            app.PowerSupplyDropDown_2 = uidropdown(app.Tab);
            app.PowerSupplyDropDown_2.Items = {'Address'};
            app.PowerSupplyDropDown_2.Value = 'Address';
            app.SignalGeneratorDropDown_2Label = uilabel(app.Tab);
            app.SignalGeneratorDropDown_2Label.Text = 'Signal Generator';
            app.SignalGeneratorDropDown_2 = uidropdown(app.Tab);
            app.SignalGeneratorDropDown_2.Items = {'Address'};
            app.SignalGeneratorDropDown_2.Value = 'Address';
            app.SpectrumAnalyzerDropDown_2Label = uilabel(app.Tab);
            app.SpectrumAnalyzerDropDown_2Label.Text = 'Spectrum Analyzer';
            app.SpectrumAnalyzerDropDown_2 = uidropdown(app.Tab);
            app.SpectrumAnalyzerDropDown_2.Items = {'Address'};
            app.SpectrumAnalyzerDropDown_2.Value = 'Address';
            app.InstrumentConnectionsPanel = uipanel(app.Tab);
            app.InstrumentConnectionsPanel.Title = 'Instrument Connections';
            app.GridLayout15 = uigridlayout(app.InstrumentConnectionsPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end